classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        background              matlab.ui.container.Panel
        SideBar_enhancement     matlab.ui.control.StateButton
        SideBar_restoration     matlab.ui.control.StateButton
        SideBar_color           matlab.ui.control.StateButton
        SideBar_compression     matlab.ui.control.StateButton
        load                    matlab.ui.control.Button
        save                    matlab.ui.control.Button
        enhance_background      matlab.ui.container.Panel
        topbar_spatial          matlab.ui.control.StateButton
        topbar_frequency        matlab.ui.control.StateButton
        spatial                 matlab.ui.container.Panel
        enhance_image           matlab.ui.control.Image
        enhance                 matlab.ui.control.Label
        original_image          matlab.ui.control.Image
        original                matlab.ui.control.Label
        spatial_filtering       matlab.ui.container.Panel
        Boundary                matlab.ui.control.DropDown
        DropDown_3Label         matlab.ui.control.Label
        model                   matlab.ui.control.DropDown
        DropDown_2Label         matlab.ui.control.Label
        stencil                 matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        spatialFiltering        matlab.ui.control.Button
        Histogram               matlab.ui.container.Panel
        HistogramEqualization   matlab.ui.control.Button
        grayLevel               matlab.ui.control.NumericEditField
        gammaLabel_2            matlab.ui.control.Label
        Gray                    matlab.ui.container.Panel
        grayoutSlider           matlab.ui.control.RangeSlider
        grayoutSliderLabel      matlab.ui.control.Label
        grayin                  matlab.ui.control.RangeSlider
        grayinLabel             matlab.ui.control.Label
        graygamma               matlab.ui.control.NumericEditField
        gammaLabel              matlab.ui.control.Label
        Grayscale               matlab.ui.control.Button
        frequency               matlab.ui.container.Panel
        enhance_image_2         matlab.ui.control.Image
        enhance_2               matlab.ui.control.Label
        original_2              matlab.ui.control.Label
        original_image_2        matlab.ui.control.Image
        high                    matlab.ui.container.Panel
        highmode                matlab.ui.control.DropDown
        Label_6                 matlab.ui.control.Label
        highbutton              matlab.ui.control.Button
        low                     matlab.ui.container.Panel
        lowmode                 matlab.ui.control.DropDown
        Label_5                 matlab.ui.control.Label
        lowbutton               matlab.ui.control.Button
        DFT                     matlab.ui.container.Panel
        log                     matlab.ui.control.DropDown
        DropDown_3Label_3       matlab.ui.control.Label
        Displacement            matlab.ui.control.DropDown
        Label_4                 matlab.ui.control.Label
        column                  matlab.ui.control.NumericEditField
        gammaLabel_5            matlab.ui.control.Label
        row                     matlab.ui.control.NumericEditField
        gammaLabel_3            matlab.ui.control.Label
        DFTbutton               matlab.ui.control.Button
        restoration_background  matlab.ui.container.Panel
        topbar_degradation      matlab.ui.control.StateButton
        topbar_noise            matlab.ui.control.StateButton
        topbar_noise_spatial    matlab.ui.control.StateButton
        topbar_Inverse_wiener   matlab.ui.control.StateButton
        topbar_reconstructed    matlab.ui.control.StateButton
        degradation             matlab.ui.container.Panel
        PSFLabel                matlab.ui.control.Label
        Label_8                 matlab.ui.control.Label
        Label_7                 matlab.ui.control.Label
        originImage             matlab.ui.control.Image
        degradedImage           matlab.ui.control.Image
        Motion                  matlab.ui.control.Button
        Gaussian                matlab.ui.control.Button
        Defocusing              matlab.ui.control.Button
        PSFImage                matlab.ui.control.UIAxes
        noise                   matlab.ui.container.Panel
        Label_11                matlab.ui.control.Label
        Label_10                matlab.ui.control.Label
        Label_9                 matlab.ui.control.Label
        originImage_2           matlab.ui.control.Image
        degradedImage_2         matlab.ui.control.Image
        Gaussian_2              matlab.ui.control.Button
        uniformly               matlab.ui.control.Button
        salt                    matlab.ui.control.Button
        PSFImage_2              matlab.ui.control.UIAxes
        noise_spatial           matlab.ui.container.Panel
        Label_27                matlab.ui.control.Label
        plural_images           matlab.ui.control.Image
        Mean_salt               matlab.ui.container.Panel
        stencil_8               matlab.ui.control.DropDown
        Label_26                matlab.ui.control.Label
        Meansalt                matlab.ui.control.Button
        Sequential_salt         matlab.ui.container.Panel
        stencil_7               matlab.ui.control.DropDown
        Label_25                matlab.ui.control.Label
        Sequentialsalt          matlab.ui.control.Button
        Mean_Gaussian           matlab.ui.container.Panel
        stencil_6               matlab.ui.control.DropDown
        Label_24                matlab.ui.control.Label
        MeanGaussian            matlab.ui.control.Button
        Sequential_Gaussian     matlab.ui.container.Panel
        stencil_5               matlab.ui.control.DropDown
        Label_23                matlab.ui.control.Label
        SequentialGaussian      matlab.ui.control.Button
        mixed                   matlab.ui.container.Panel
        stencil_4               matlab.ui.control.DropDown
        Label_22                matlab.ui.control.Label
        mixedbutton             matlab.ui.control.Button
        Label_12                matlab.ui.control.Label
        originImage_3           matlab.ui.control.Image
        Inverse_wiener          matlab.ui.container.Panel
        Inverse                 matlab.ui.container.Panel
        stencil_9               matlab.ui.control.DropDown
        Label_29                matlab.ui.control.Label
        Inversebutton           matlab.ui.control.Button
        Wiener                  matlab.ui.container.Panel
        Wienerbutton            matlab.ui.control.Button
        Label_28                matlab.ui.control.Label
        plural_images_2         matlab.ui.control.Image
        Label_13                matlab.ui.control.Label
        originImage_4           matlab.ui.control.Image
        reconstructed           matlab.ui.container.Panel
        Back_projection         matlab.ui.container.Panel
        Label_38                matlab.ui.control.Label
        model_2                 matlab.ui.control.DropDown
        projection              matlab.ui.control.Button
        Fourier_slice           matlab.ui.container.Panel
        degree_1                matlab.ui.control.Slider
        Label_39                matlab.ui.control.Label
        Fourier                 matlab.ui.control.Button
        Label_37                matlab.ui.control.Label
        Label_35                matlab.ui.control.Label
        RAdon                   matlab.ui.container.Panel
        degree                  matlab.ui.control.Slider
        Label_40                matlab.ui.control.Label
        Label_36                matlab.ui.control.Label
        Radon                   matlab.ui.control.Button
        Label_31                matlab.ui.control.Label
        Label_14                matlab.ui.control.Label
        originImage_5           matlab.ui.control.Image
        PSFImage_4              matlab.ui.control.UIAxes
        PSFImage_3              matlab.ui.control.UIAxes
        color_background        matlab.ui.container.Panel
        topbar_color            matlab.ui.control.StateButton
        topbar_Pseudocolor      matlab.ui.control.StateButton
        topbar_Full_color       matlab.ui.control.StateButton
        color                   matlab.ui.container.Panel
        Convert                 matlab.ui.control.Button
        RGBHSILabel             matlab.ui.control.Label
        originImage_11          matlab.ui.control.Image
        Label_43                matlab.ui.control.Label
        lightImage              matlab.ui.control.Image
        HSI                     matlab.ui.control.Button
        RGB                     matlab.ui.control.Button
        Label_42                matlab.ui.control.Label
        Label_41                matlab.ui.control.Label
        ILabel                  matlab.ui.control.Label
        SLabel                  matlab.ui.control.Label
        HLabel                  matlab.ui.control.Label
        colorImage              matlab.ui.control.Image
        enhanceImage            matlab.ui.control.Image
        IImage                  matlab.ui.control.Image
        SImage                  matlab.ui.control.Image
        HImage                  matlab.ui.control.Image
        Label_15                matlab.ui.control.Label
        originImage_6           matlab.ui.control.Image
        Pseudocolor             matlab.ui.container.Panel
        Pseudocolorbutton       matlab.ui.control.Button
        Label_32                matlab.ui.control.Label
        PseudocolorImage        matlab.ui.control.Image
        Label_16                matlab.ui.control.Label
        originImage_7           matlab.ui.control.Image
        Full_color              matlab.ui.container.Panel
        HSIbutton               matlab.ui.control.Button
        RGBbutton               matlab.ui.control.Button
        Label_33                matlab.ui.control.Label
        Full_colorImage         matlab.ui.control.Image
        Label_17                matlab.ui.control.Label
        originImage_8           matlab.ui.control.Image
        compression_background  matlab.ui.container.Panel
        topbar_Huffman          matlab.ui.control.StateButton
        topbar_Predictive       matlab.ui.control.StateButton
        Huffman                 matlab.ui.container.Panel
        Label_44                matlab.ui.control.Label
        HTML                    matlab.ui.control.HTML
        Huffmanbutton           matlab.ui.control.Button
        Label_18                matlab.ui.control.Label
        originImage_9           matlab.ui.control.Image
        Predictive              matlab.ui.container.Panel
        predictbutton           matlab.ui.control.Button
        Label_45                matlab.ui.control.Label
        Label_19                matlab.ui.control.Label
        originImage_10          matlab.ui.control.Image
        predictImage            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        original_image_data % Description
        enhance_image_data
        tempFiles % map controlName -> temp filepath for ImageSource
    end
    
    methods (Access = private)
        function ctrl = findControl(app, names)
            ctrl = [];
            for k = 1:numel(names)
                n = names{k};
                if isprop(app, n)
                    try
                        c = app.(n);
                        if ~isempty(c)
                            ctrl = c; return;
                        end
                    catch
                    end
                end
            end
        end

        function img = getControlImage(app, names)
            img = [];
            ctrl = findControl(app, names);
                if isempty(ctrl)
                    return;
                end
            % Prefer numeric image stored in UserData. If UserData exists
            % but contains a filename string (previous bug), try to read it.
            try
                if isprop(ctrl,'UserData') && ~isempty(ctrl.UserData)
                    ud = ctrl.UserData;
                    if isnumeric(ud) || islogical(ud)
                        img = ud; return;
                    elseif ischar(ud) || isstring(ud)
                        try img = imread(char(ud)); return; catch, end
                    end
                end
            catch
            end

            % Next try ImageSource: it may be a numeric image, a file path,
            % or another value. If it's a file path, read it into matrix.
            try
                if isprop(ctrl,'ImageSource') && ~isempty(ctrl.ImageSource)
                    src = ctrl.ImageSource;
                    if isnumeric(src) || islogical(src)
                        img = src; return;
                    elseif ischar(src) || isstring(src)
                        try img = imread(char(src)); return; catch, end
                    end
                end
            catch
            end
        end

        function setControlImage(app, names, img)
            ctrl = findControl(app, names);
            if isempty(ctrl), return; end
            % If img is numeric or logical, keep matrix in UserData for processing
            try
                if isnumeric(img) || islogical(img)
                    ctrl.UserData = img;
                end
            catch
            end

            % If img is numeric, write to a temporary PNG and set ImageSource to the file
            try
                if isnumeric(img) || islogical(img)
                    % determine canonical control name that matched
                    ctrlName = '';
                    for k = 1:numel(names)
                        n = names{k};
                        if isprop(app, n)
                            try
                                if isequal(app.(n), ctrl)
                                    ctrlName = n; break;
                                end
                            catch
                            end
                        end
                    end

                    % prepare temp file
                    tmp = [tempname() '.png'];
                    try
                        % normalize image to uint8 if needed
                        im = img;
                        if isfloat(im)
                            if max(im(:)) <= 1
                                im = im2uint8(im);
                            else
                                im = uint8(im);
                            end
                        elseif islogical(im)
                            im = uint8(im) * 255;
                        end
                        imwrite(im, tmp);
                        % remove previous temp for this control
                        try
                            if ~isempty(ctrlName) && isprop(app, 'tempFiles') && isKey(app.tempFiles, ctrlName)
                                old = app.tempFiles(ctrlName);
                                if exist(old, 'file')
                                    delete(old);
                                end
                            end
                        catch
                        end
                        % set new temp path
                        try
                            if ~isempty(ctrlName) && isprop(app, 'tempFiles')
                                app.tempFiles(ctrlName) = tmp;
                            end
                        catch
                        end
                        % set ImageSource to the temp file so uiimage updates
                        try
                            ctrl.ImageSource = tmp;
                        catch
                        end
                        return;
                    catch
                        % if writing fails, fallback to setting ImageSource to matrix
                        try ctrl.ImageSource = img; end
                    end
                else
                    % non-numeric input: could be a filename or other
                    if (ischar(img) || isstring(img)) && exist(char(img), 'file')
                        % set ImageSource to the file and also try to cache
                        % the numeric matrix in UserData for faster access
                        try
                            data = imread(char(img));
                            try ctrl.UserData = data; end
                        catch
                        end
                        try ctrl.ImageSource = char(img); end
                    else
                        % unknown/non-file value -> assign as ImageSource
                        try ctrl.ImageSource = img; end
                    end
                end
            catch
                % last resort: try assign ImageSource
                try ctrl.ImageSource = img; end
            end
        end
        
       
        % HSI到RGB转换
        function rgb = hsi2rgb_app(app, H, S, I)
            % 将H从[0,1]转换到[0,2pi]
            H_2pi = H * 2 * pi;
            
            % 初始化RGB
            R = zeros(size(H));
            G = zeros(size(H));
            B = zeros(size(H));
            
            % 情况1: H在[0, 2pi/3]
            idx1 = (H_2pi >= 0) & (H_2pi < 2*pi/3);
            B(idx1) = I(idx1) .* (1 - S(idx1));
            R(idx1) = I(idx1) .* (1 + (S(idx1) .* cos(H_2pi(idx1))) ./ ...
                                  (cos(pi/3 - H_2pi(idx1)) + eps));
            G(idx1) = 3 * I(idx1) - (B(idx1) + R(idx1));
            
            % 情况2: H在[2pi/3, 4pi/3]
            idx2 = (H_2pi >= 2*pi/3) & (H_2pi < 4*pi/3);
            H_temp = H_2pi(idx2) - 2*pi/3;
            R(idx2) = I(idx2) .* (1 - S(idx2));
            G(idx2) = I(idx2) .* (1 + (S(idx2) .* cos(H_temp)) ./ ...
                                  (cos(pi/3 - H_temp) + eps));
            B(idx2) = 3 * I(idx2) - (R(idx2) + G(idx2));
            
            % 情况3: H在[4pi/3, 2pi]
            idx3 = (H_2pi >= 4*pi/3) & (H_2pi <= 2*pi);
            H_temp = H_2pi(idx3) - 4*pi/3;
            G(idx3) = I(idx3) .* (1 - S(idx3));
            B(idx3) = I(idx3) .* (1 + (S(idx3) .* cos(H_temp)) ./ ...
                                  (cos(pi/3 - H_temp) + eps));
            R(idx3) = 3 * I(idx3) - (G(idx3) + B(idx3));
            
            % 限制在[0,1]
            R = min(max(R, 0), 1);
            G = min(max(G, 0), 1);
            B = min(max(B, 0), 1);
            
            rgb = cat(3, R, G, B);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.tempFiles = containers.Map();
            catch
                app.tempFiles = containers.Map('KeyType','char','ValueType','char');
            end
            try
                app.topbar_spatial.Value = true;
                app.topbar_frequency.Value = false;
                app.spatial.Visible = true;
                app.frequency.Visible = false;
            catch
    end
        end

        % Value changed function: SideBar_enhancement
        function SideBar_enhancementValueChanged(app, event)
            value = app.SideBar_enhancement.Value;
            %互斥逻辑
            app.SideBar_restoration.Value = false;
            app.SideBar_color.Value = false;
            app.SideBar_compression.Value = false;
            %侧边栏逻辑
            app.enhance_background.Visible = true;
            app.restoration_background.Visible = false;
            app.color_background.Visible = false;
            app.compression_background.Visible = false;
        end

        % Value changed function: SideBar_restoration
        function SideBar_restorationValueChanged(app, event)
            value = app.SideBar_restoration.Value;
            %互斥逻辑
            app.SideBar_enhancement.Value = false;
            app.SideBar_color.Value = false;
            app.SideBar_compression.Value = false;
            %侧边栏逻辑
            app.enhance_background.Visible = false;
            app.restoration_background.Visible = true;
            app.color_background.Visible = false;
            app.compression_background.Visible = false;
        end

        % Value changed function: SideBar_color
        function SideBar_colorValueChanged(app, event)
            value = app.SideBar_color.Value;
            %互斥逻辑
            app.SideBar_enhancement.Value = false;
            app.SideBar_restoration.Value = false;
            app.SideBar_compression.Value = false;
            %侧边栏逻辑
            app.enhance_background.Visible = false;
            app.restoration_background.Visible = false;
            app.color_background.Visible = true;
            app.compression_background.Visible = false;
        end

        % Value changed function: SideBar_compression
        function SideBar_compressionValueChanged(app, event)
            value = app.SideBar_compression.Value;
            %互斥逻辑
            app.SideBar_enhancement.Value = false;
            app.SideBar_restoration.Value = false;
            app.SideBar_color.Value = false;
            %侧边栏逻辑
            app.enhance_background.Visible = false;
            app.restoration_background.Visible = false;
            app.color_background.Visible = false;
            app.compression_background.Visible = true;
        end

        % Button pushed function: load
        function loadButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files'});
            if isequal(filename, 0)
                return;
            end
            filepath = fullfile(pathname, filename);
            img = imread(filepath);

            % Fast path: set uiimage ImageSource to the file path to avoid
            % writing temporary PNGs for the same image twice. Also store
            % the matrix in UserData and app properties for processing.
            % First, set commonly used original/enhance controls explicitly
            origControls = {'original_image','OriginalImage','originalImage',  ...
                            'original_image_2','OriginalImage2','originalImage2', ...
                            'originImage','originImage_2','original_image_restoration'};
            for k = 1:numel(origControls)
                try
                    setControlImage(app, origControls(k), filepath);
                catch
                    try setControlImage(app, origControls(k), img); end
                end
            end

            try
                % set enhance image display to same file (no duplicate write)
                setControlImage(app, {'enhance_image','ProcessedImage','enhanceImage','enhanced_image'}, filepath);
            catch
                setControlImage(app, {'enhance_image','ProcessedImage','enhanceImage','enhanced_image'}, img);
            end
            % Also set frequency-panel images (if present) to the same file
            try
                setControlImage(app, {'original_image_2','OriginalImage2','originalImage2'}, filepath);
            catch
                try setControlImage(app, {'original_image_2','OriginalImage2','originalImage2'}, img); end
            end
            try
                setControlImage(app, {'enhance_image_2','ProcessedImage2','enhanceImage2','enhanced_image_2'}, filepath);
            catch
                try setControlImage(app, {'enhance_image_2','ProcessedImage2','enhanceImage2','enhanced_image_2'}, img); end
            end

            % store matrix in control UserData and app properties so
            % subsequent processing doesn't need to re-imread the file.
            try app.original_image_data = img; end
            try app.enhance_image_data = img; end

            % Also ensure any uiimage property on the app that is still
            % empty gets set to the loaded image. This covers panels or
            % controls that weren't explicitly enumerated above.
            try
                propList = properties(app);
                for i = 1:numel(propList)
                    pname = propList{i};
                    try
                        ctrl = app.(pname);
                    catch
                        continue;
                    end
                    % Only operate on uiimage controls
                    try
                        if isa(ctrl, 'matlab.ui.control.Image')
                            emptySource = true;
                            try emptySource = isempty(ctrl.ImageSource); end
                            emptyUser = true;
                            try emptyUser = isempty(ctrl.UserData); end
                            % If either ImageSource or UserData is empty, set both
                            if emptySource || emptyUser
                                try
                                    setControlImage(app, {pname}, filepath);
                                catch
                                    try setControlImage(app, {pname}, img); end
                                end
                                try ctrl.UserData = img; end
                            end
                        end
                    catch
                        % ignore controls that error on inspection
                    end
                end
            catch
                % non-fatal if reflection fails
            end

            % 如果存在 ImageInfoLabel，则显示图像信息
            try
                [rows, cols, channels] = size(app.original_image_data);
                info_text = sprintf('Size: %dx%d, Channels: %d, Class: %s', rows, cols, channels, class(app.original_image_data));
                if isprop(app, 'ImageInfoLabel') && isa(app.ImageInfoLabel, 'matlab.ui.control.Label')
                    app.ImageInfoLabel.Text = info_text;
                end
            catch
                % ignore if label not present
            end
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            % 尝试从各个处理图像控件获取图像
            img = getControlImage(app, {'enhance_image','ProcessedImage','enhanceImage','enhanced_image', ...
                'enhance_image_2','plural_images','plural_images_2','Full_colorImage','PseudocolorImage'});
            
            if isempty(img)
                uialert(app.UIFigure, '没有可保存的处理后图像！', '错误'); 
                return;
            end
            
            % 打开保存对话框
            [filename, pathname] = uiputfile({'*.png', 'PNG图像'; '*.jpg', 'JPEG图像'; '*.bmp', 'BMP图像'; '*.tif', 'TIFF图像'}, '保存图像');
            if isequal(filename, 0)
                return; % 用户取消
            end
            
            filepath = fullfile(pathname, filename);
            
            try
                % 保存图像
                imwrite(img, filepath);
                uialert(app.UIFigure, ['图像已成功保存至: ' newline filepath], '保存成功', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ['保存失败: ' ME.message], '错误');
            end
        end

        % Value changed function: topbar_spatial
        function topbar_spatialValueChanged(app, event)
            value = app.topbar_spatial.Value;
            %互斥逻辑
            app.topbar_frequency.Value = false;
            %顶部导航栏逻辑
            app.spatial.Visible = true;
            app.frequency.Visible = false;
        end

        % Value changed function: topbar_frequency
        function topbar_frequencyValueChanged(app, event)
            value = app.topbar_frequency.Value;
            %互斥逻辑
            app.topbar_spatial.Value = false;
            %顶部导航栏逻辑
            app.frequency.Visible = true;
            app.spatial.Visible = false;
        end

        % Button pushed function: Grayscale
        function GrayscaleButtonPushed(app, event)
            disp('GrayscaleButtonPushed called');
            img = getControlImage(app, {'original_image','OriginalImage','originalImage'});
            if ~isempty(img)
                try disp(['Grayscale input size: ' mat2str(size(img)) ', class: ' class(img)]); end
            else
                disp('Grayscale: getControlImage returned empty');
            end
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); return;
            end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % read parameters
            gamma = app.graygamma.Value;             % numeric edit field
            grayin = app.grayin.Value;               % range slider -> [low high] or scalar
            % prefer grayoutSlider (range) when available; fall back to grayout scalar
            if isprop(app, 'grayoutSlider') && ~isempty(app.grayoutSlider) && numel(app.grayoutSlider.Value)==2
                grayout_val = app.grayoutSlider.Value;
                grayout_source = 'grayoutSlider';
            else
                grayout_val = app.grayout.Value;
                grayout_source = 'grayout';
            end

            % normalize/validate parameters
            if isempty(gamma) || ~isnumeric(gamma)
                gamma = 1; % default gamma
            end

            % Determine input range from grayin control (range slider)
            try
                if isprop(app, 'grayin') && ~isempty(app.grayin) && numel(app.grayin.Value)==2
                    inRange = double(app.grayin.Value(:)');
                else
                    % fallback: use automatic stretchlim with default tolerance
                    inRange = stretchlim(img);
                end
            catch
                inRange = [0 1];
            end

            % Decide outRange: prefer grayoutSlider (range) if present, otherwise default [0 1]
            if exist('grayout_val','var') && numel(grayout_val) == 2
                outRange = double(grayout_val(:)');
            else
                outRange = [0 1];
            end
            outRange = max(0, min(1, outRange));

            % debug print actual parameters used
            try
                disp(['Grayscale params -> gamma: ' num2str(gamma) ', inRange: [' num2str(inRange) '], outRange: [' num2str(outRange) '], outSource: ' grayout_source]);
            catch
            end

            % if parameters imply identity mapping and gamma==1, warn (no visible change)
            try
                if isequal(round(inRange,6), round(outRange,6)) && abs(gamma-1) < 1e-6
                    disp('Warning: inRange == outRange and gamma==1 -> result will be identical to input');
                end
            catch
            end

            % perform adjustment
            adj = imadjust(im2double(img), inRange, outRange, gamma);
            outImg = im2uint8(adj);
            
            setControlImage(app, {'enhance_image','ProcessedImage','enhanceImage','enhanced_image'}, outImg);
            try app.enhance_image_data = outImg; end
        end

        % Button pushed function: HistogramEqualization
        function HistogramEqualizationButtonPushed(app, event)
            disp('HistogramEqualizationButtonPushed called');
            img = getControlImage(app, {'original_image','OriginalImage','originalImage'});
            if ~isempty(img)
                try disp(['HistEq input size: ' mat2str(size(img)) ', class: ' class(img)]); end
            else
                disp('HistEq: getControlImage returned empty');
            end
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            nLevels = app.grayLevel.Value; % 0 表示用默认 histeq
            if nLevels>0
                img2 = uint8(double(img)/255*(nLevels-1));
                outImg = histeq(img2, nLevels);
            else
                outImg = histeq(img);
            end
            
            setControlImage(app, {'enhance_image','ProcessedImage','enhanceImage','enhanced_image'}, outImg);
            try app.enhance_image_data = outImg; end
        end

        % Button pushed function: spatialFiltering
        function spatialFilteringButtonPushed(app, event)
            % Enhanced spatial filtering callback
            disp('spatialFilteringButtonPushed called');
            img = getControlImage(app, {'original_image','OriginalImage','originalImage'});
            if ~isempty(img)
                try disp(['SpatialFilter input size: ' mat2str(size(img)) ', class: ' class(img)]); end
            else
                disp('SpatialFilter: getControlImage returned empty');
            end
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end

            % For filtering we prefer a single-channel image
            if size(img,3)==3
                imgGray = rgb2gray(img);
            else
                imgGray = img;
            end

            % read UI parameters
            stencil = '';
            try stencil = app.stencil.Value; catch, end
            model   = 'corr';
            try model = app.model.Value; catch, end
            bound   = 'replicate';
            try bound = app.Boundary.Value; catch, end

            % determine kernel size if user provided a control named stencilSize
            ksize = [];
            try
                if isprop(app, 'stencilSize') && ~isempty(app.stencilSize) && isnumeric(app.stencilSize.Value)
                    ksize = max(1, round(double(app.stencilSize.Value)));
                end
            catch
            end

            % decide kernel/filter h based on stencil selection
            switch stencil
                case '全1矩阵'
                    if isempty(ksize), k = 3; else k = ksize; end
                    h = ones(k,k) / (k*k);
                case '全0矩阵'
                    if isempty(ksize), k = 3; else k = ksize; end
                    h = zeros(k,k);
                case '高斯'
                    if isempty(ksize), k = 3; else k = ksize; end
                    sigma = max(0.5, k/6); % reasonable sigma default
                    h = fspecial('gaussian', [k k], sigma);
                case '锐化'
                    % common sharpening kernel (3x3)
                    h = [0 -1 0; -1 5 -1; 0 -1 0];
                otherwise
                    % allow user to place a custom kernel in app.customKernel if exists
                    h = [];
                    try
                        if isprop(app, 'customKernel') && ~isempty(app.customKernel) && ~isempty(app.customKernel.UserData)
                            h = app.customKernel.UserData;
                        end
                    catch
                    end
                    if isempty(h)
                        % fallback to 3x3 average
                        h = ones(3,3)/9;
                    end
            end

            % boundary option normalization: allow '0' -> use 'replicate'
            switch bound
                case '0'
                    boundary_option = 'replicate';
                otherwise
                    boundary_option = bound;
            end

            % imfilter expects numeric double/float for kernel operations
            try
                fimg = double(imgGray);
            catch
                fimg = im2double(imgGray);
            end

            % use 'same' output size to keep original dimensions
            size_option = 'same';

            % perform filtering
            try
                disp(['SpatialFilter params -> stencil: ' char(stencil) ', model: ' char(model) ', boundary: ' char(boundary_option) ', kernel_size: ' num2str(size(h))]);
            catch
            end
            try
                % imfilter signature: imfilter(A, H, method, boundary, shape)
                % but historically some call orders differ; use name-based safe call
                filtered = imfilter(fimg, h, model, boundary_option, size_option);
            catch ME
                % try alternative ordering if above fails
                try
                    filtered = imfilter(fimg, h, boundary_option, model);
                catch
                    disp(['imfilter failed: ' ME.message]);
                    uialert(app.UIFigure, ['滤波失败: ' ME.message], '错误');
                    return;
                end
            end

            % scale/convert result back to uint8 for display
            try
                % Try to preserve the input dynamic range and avoid an
                % expensive global mat2gray when possible.
                if isa(imgGray, 'uint8')
                    % filtered is double; round and cast to uint8
                    outImg = uint8(max(0, min(255, round(filtered))));
                elseif isa(imgGray, 'logical')
                    outImg = uint8(filtered > 0) * 255;
                elseif isa(imgGray, 'double')
                    % if double assumed in [0,1], directly use im2uint8
                    % otherwise fall back to mat2gray
                    mx = max(filtered(:)); mn = min(filtered(:));
                    if mx <= 1 && mn >= 0
                        outImg = im2uint8(filtered);
                    else
                        outImg = im2uint8(mat2gray(filtered));
                    end
                else
                    outImg = im2uint8(mat2gray(filtered));
                end
            catch
                try outImg = uint8(filtered); catch outImg = im2uint8(filtered); end
            end

            setControlImage(app, {'enhance_image','ProcessedImage','enhanceImage','enhanced_image'}, outImg);
            try app.enhance_image_data = outImg; end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        try
            if isprop(app, 'tempFiles') && isa(app.tempFiles, 'containers.Map')
                keysList = app.tempFiles.keys;
                for kk = 1:numel(keysList)
                    f = app.tempFiles(keysList{kk});
                    try
                        if exist(f, 'file')
                            delete(f);
                        end
                    catch
                    end
                end
            end
        catch
        end
        
        % finally delete app (this will close the UIFigure and clean up object)
        try
            delete(app);
        catch
            % if deletion fails, at least close the figure
            try
                delete(app.UIFigure);
            catch
            end
        end
        end

        % Button pushed function: DFTbutton
        function DFTbuttonButtonPushed(app, event)
            disp('DFTbuttonPushed called');
            % get image from frequency panel if present, else from original
            img = getControlImage(app, {'original_image_2','OriginalImage2','originalImage2', 'original_image','OriginalImage','originalImage'});
            if isempty(img)
                uialert(app.UIFigure, '请先加载图片', '错误'); return;
            end
            if size(img,3) > 1
                img = rgb2gray(img);
            end

            % read parameters
            try
                M = double(app.row.Value);
            catch
                M = size(img,1);
            end
            try
                N = double(app.column.Value);
            catch
                N = size(img,2);
            end
            % validate sizes: if zero or empty use image size
            if isempty(M) || M<=0, M = size(img,1); end
            if isempty(N) || N<=0, N = size(img,2); end

            % read options
            use_log = true;
            try
                if isprop(app, 'log') && ~isempty(app.log)
                    v = app.log.Value;
                    if ischar(v) || isstring(v)
                        use_log = strcmpi(char(v), 'true');
                    else
                        use_log = logical(v);
                    end
                end
            catch
            end
            use_shift = true;
            try
                if isprop(app, 'Displacement') && ~isempty(app.Displacement)
                    v = app.Displacement.Value;
                    if ischar(v) || isstring(v)
                        use_shift = strcmpi(char(v), 'true');
                    else
                        use_shift = logical(v);
                    end
                end
            catch
            end

            % compute padded sizes P,Q as powers of 2 >= image dims or provided M,N
            [origM, origN] = size(img);
            P = 2^nextpow2(max(origM, M));
            Q = 2^nextpow2(max(origN, N));

            disp(['DFT params -> P: ' num2str(P) ', Q: ' num2str(Q) ', use_shift: ' num2str(use_shift) ', use_log: ' num2str(use_log)]);

            % compute DFT
            F = fft2(double(img), P, Q);
            if use_shift
                F_disp = fftshift(F);
            else
                F_disp = F;
            end
            S = abs(F_disp);
            if use_log
                S_disp = log(1 + S);
            else
                S_disp = S;
            end

            % normalize for display and convert to uint8
            try
                S_norm = mat2gray(S_disp);
                outImg = im2uint8(S_norm);
            catch
                outImg = im2uint8(S_disp/max(S_disp(:)));
            end

            % show result in frequency enhance image control
            setControlImage(app, {'enhance_image_2','ProcessedImage2','enhanceImage2','enhanced_image_2'}, outImg);
            try app.enhance_image_data = outImg; end
        end

        % Button pushed function: lowbutton
        function lowbuttonButtonPushed(app, event)
                        % Single-mode implementation of low-pass filtering
            % Logic matches the high-pass implementation but for low-pass filters.
            
            disp('lowbuttonPushed called');
            
            % 1. Get Input Image
            img = getControlImage(app, {'original_image_2'}); 
            if isempty(img)
                img = getControlImage(app, {'original_image'});
            end
            
            if isempty(img)
                uialert(app.UIFigure, '请先加载图片', '错误'); return;
            end
            
            if size(img,3) > 1
                Ig = rgb2gray(img);
            else
                Ig = img;
            end
            Ig = double(Ig);

            % 2. Parameters
            D0 = 50; 
            n = 2;
            try
                if isprop(app,'column') && ~isempty(app.column.Value)
                    val = double(app.column.Value);
                    if val > 0, D0 = val; end
                end
            catch
            end
            try
                if isprop(app,'row') && ~isempty(app.row.Value)
                    val = double(app.row.Value);
                    if val > 0, n = val; end
                end
            catch
            end

            % 3. FFT and Distance Matrix
            [M,N] = size(Ig);
            FI = fft2(Ig);
            Fc = fftshift(FI);

            center1 = floor(M/2);
            center2 = floor(N/2);

            [X, Y] = meshgrid(1:N, 1:M); 
            D = sqrt((Y - center1).^2 + (X - center2).^2);
            % Handle d=0 if needed (less critical for low pass but good for consistency)
            D(D == 0) = 0.001;

            % 4. Select Mode and Construct Filter
            mode = '理想低通';
            try
                if isprop(app, 'lowmode')
                    mode = app.lowmode.Value;
                end
            catch
            end
            
            disp(['Selected Low-pass Mode: ' char(mode)]);

            switch mode
                case '理想低通'
                    H = double(D <= D0);
                case '高斯'
                    H = exp(-(D.^2) ./ (2 * (D0^2)));
                case '巴特沃斯'
                    H = 1 ./ (1 + (D ./ D0).^(2 * n));
                otherwise
                    H = double(D <= D0);
            end

            % 5. Apply Filter and Inverse FFT
            Fh = Fc .* H;
            I_out = real(ifft2(ifftshift(Fh)));

            % 6. Scale to uint8
            mn = min(I_out(:)); mx = max(I_out(:));
            if mx > mn
                outImg = im2uint8((I_out - mn) / (mx - mn));
            else
                outImg = im2uint8(I_out);
            end

            % 7. Show Result
            setControlImage(app, {'enhance_image_2'}, outImg);
            try app.enhance_image_data = outImg; end
            
            disp(['Low-pass filtering done. Mode=' char(mode) ', D0=' num2str(D0) ', n=' num2str(n)]);
        end

        % Button pushed function: highbutton
        function highbuttonButtonPushed(app, event)
            % Single-mode implementation of high-pass filtering
            % Logic matches the reference script but selects only ONE filter based on dropdown.
            
            disp('highbuttonPushed called (Single Mode)');
            
            % 1. Get Input Image
            img = getControlImage(app, {'original_image_2'}); 
            if isempty(img)
                img = getControlImage(app, {'original_image'});
            end
            
            if isempty(img)
                uialert(app.UIFigure, '请先加载图片', '错误'); return;
            end
            
            if size(img,3) > 1
                Ig = rgb2gray(img);
            else
                Ig = img;
            end
            Ig = double(Ig);

            % 2. Parameters
            D0 = 50; 
            n = 2;
            try
                if isprop(app,'column') && ~isempty(app.column.Value)
                    val = double(app.column.Value);
                    if val > 0, D0 = val; end
                end
            catch
            end
            try
                if isprop(app,'row') && ~isempty(app.row.Value)
                    val = double(app.row.Value);
                    if val > 0, n = val; end
                end
            catch
            end

            % 3. FFT and Distance Matrix
            [M,N] = size(Ig);
            FI = fft2(Ig);
            Fc = fftshift(FI);

            center1 = floor(M/2);
            center2 = floor(N/2);

            [X, Y] = meshgrid(1:N, 1:M); 
            D = sqrt((Y - center1).^2 + (X - center2).^2);
            D(D == 0) = 0.001;

            % 4. Select Mode and Construct Filter
            mode = '理想高通';
            try
                if isprop(app, 'highmode')
                    mode = app.highmode.Value;
                end
            catch
            end
            
            disp(['Selected High-pass Mode: ' char(mode)]);

            switch mode
                case '理想高通'
                    H = double(D > D0);
                case '高斯'
                    H = 1 - exp(-(D.^2) ./ (2 * (D0^2)));
                case '巴特沃斯'
                    H = 1 ./ (1 + (D0 ./ D).^(2 * n));
                otherwise
                    % Default fallback
                    H = double(D > D0);
            end

            % 5. Apply Filter and Inverse FFT
            Fh = Fc .* H;
            I_out = real(ifft2(ifftshift(Fh)));

            % 6. Scale to uint8 (imshow([], []) behavior)
            mn = min(I_out(:)); mx = max(I_out(:));
            if mx > mn
                outImg = im2uint8((I_out - mn) / (mx - mn));
            else
                outImg = im2uint8(I_out);
            end

            % 7. Show Result
            setControlImage(app, {'enhance_image_2'}, outImg);
            try app.enhance_image_data = outImg; end
            
            disp(['High-pass filtering done. Mode=' char(mode) ', D0=' num2str(D0) ', n=' num2str(n)]);
        end

        % Value changed function: topbar_degradation
        function topbar_degradationValueChanged(app, event)
            value = app.topbar_degradation.Value;
            %互斥逻辑
            app.topbar_noise.Value = false;
            app.topbar_noise_spatial.Value = false;
            app.topbar_Inverse_wiener.Value = false;
            app.topbar_reconstructed.Value = false;
            %顶部导航栏逻辑
            app.degradation.Visible = true;
            app.noise.Visible = false;
            app.noise_spatial.Visible = false;
            app.Inverse_wiener.Visible = false;
            app.reconstructed.Visible = false;
        end

        % Value changed function: topbar_noise
        function topbar_noiseValueChanged(app, event)
            value = app.topbar_noise.Value;
            %互斥逻辑
            app.topbar_degradation.Value = false;
            app.topbar_noise_spatial.Value = false;
            app.topbar_Inverse_wiener.Value = false;
            app.topbar_reconstructed.Value = false;
            %顶部导航栏逻辑
            app.degradation.Visible = false;
            app.noise.Visible = true;
            app.noise_spatial.Visible = false;
            app.Inverse_wiener.Visible = false;
            app.reconstructed.Visible = false;
        end

        % Value changed function: topbar_noise_spatial
        function topbar_noise_spatialValueChanged(app, event)
            value = app.topbar_noise_spatial.Value;
            %互斥逻辑
            app.topbar_degradation.Value = false;
            app.topbar_noise.Value = false;
            app.topbar_Inverse_wiener.Value = false;
            app.topbar_reconstructed.Value = false;
            %顶部导航栏逻辑
            app.degradation.Visible = false;
            app.noise.Visible = false;
            app.noise_spatial.Visible = true;
            app.Inverse_wiener.Visible = false;
            app.reconstructed.Visible = false;
        end

        % Value changed function: topbar_Inverse_wiener
        function topbar_Inverse_wienerValueChanged(app, event)
            value = app.topbar_Inverse_wiener.Value;
            %互斥逻辑
            app.topbar_degradation.Value = false;
            app.topbar_noise.Value = false;
            app.topbar_noise_spatial.Value = false;
            app.topbar_reconstructed.Value = false;
            %顶部导航栏逻辑
            app.degradation.Visible = false;
            app.noise.Visible = false;
            app.noise_spatial.Visible = false;
            app.Inverse_wiener.Visible = true;
            app.reconstructed.Visible = false;
        end

        % Value changed function: topbar_reconstructed
        function topbar_reconstructedValueChanged(app, event)
            value = app.topbar_reconstructed.Value;
            %互斥逻辑
            app.topbar_degradation.Value = false;
            app.topbar_noise.Value = false;
            app.topbar_noise_spatial.Value = false;
            app.topbar_Inverse_wiener.Value = false;
            %顶部导航栏逻辑
            app.degradation.Visible = false;
            app.noise.Visible = false;
            app.noise_spatial.Visible = false;
            app.Inverse_wiener.Visible = false;
            app.reconstructed.Visible = true;
        end

        % Button pushed function: Motion
        function MotionButtonPushed(app, event)
            % 运动模糊退化（线性运动）
            img = getControlImage(app, {'original_image','OriginalImage','originalImage','original_image_restoration','original_image_2'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end

            [M, N] = size(img);
            LEN = min(21, max(3, min(M,N)-10)); % 保证至少 3
            THETA = 45;

            psf = fspecial('motion', LEN, THETA);
            degraded = imfilter(double(img), psf, 'conv', 'replicate');

            % 根据输入范围选择转换方式
            if max(img(:)) <= 1
                out = im2uint8(mat2gray(degraded));
            else
                out = uint8(max(0, min(255, round(degraded))));
            end

            setControlImage(app, {'degradedImage'}, out);

            % 显示 PSF
            try
                imagesc(app.PSFImage, psf);
                colormap(app.PSFImage, 'gray');
                axis(app.PSFImage, 'image');
            catch
            end

            % 存储 PSF 与退化图以便后续复原使用
            try app.PSFImage.UserData = psf; end
            try app.degradedImage.UserData = out; end
        end

        % Button pushed function: Gaussian
        function GaussianButtonPushed(app, event)
            % 高斯模糊退化
            img = getControlImage(app, {'original_image','OriginalImage','originalImage','original_image_restoration','original_image_2'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end

            [M, N] = size(img);
            sigma = 3;
            hsize = min(11, max(3, min(M,N)-10));

            psf = fspecial('gaussian', hsize, sigma);
            degraded = imfilter(double(img), psf, 'conv', 'replicate');

            if max(img(:)) <= 1
                out = im2uint8(mat2gray(degraded));
            else
                out = uint8(max(0, min(255, round(degraded))));
            end

            setControlImage(app, {'degradedImage'}, out);
            try
                imagesc(app.PSFImage, psf);
                colormap(app.PSFImage, 'gray');
                axis(app.PSFImage, 'image');
            catch
            end
            try app.PSFImage.UserData = psf; end
            try app.degradedImage.UserData = out; end
        end

        % Button pushed function: Defocusing
        function DefocusingButtonPushed(app, event)
            % 散焦（圆盘）模糊退化
            img = getControlImage(app, {'original_image','OriginalImage','originalImage','original_image_restoration','original_image_2'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end

            [M, N] = size(img);
            R = min(7, max(1, floor(min(M,N)/2)-1));

            psf = fspecial('disk', R);
            degraded = imfilter(double(img), psf, 'conv', 'replicate');

            if max(img(:)) <= 1
                out = im2uint8(mat2gray(degraded));
            else
                out = uint8(max(0, min(255, round(degraded))));
            end

            setControlImage(app, {'degradedImage'}, out);
            try
                imagesc(app.PSFImage, psf);
                colormap(app.PSFImage, 'gray');
                axis(app.PSFImage, 'image');
            catch
            end
            try app.PSFImage.UserData = psf; end
            try app.degradedImage.UserData = out; end
        end

        % Button pushed function: Gaussian_2
        function Gaussian_2ButtonPushed(app, event)
            % 高斯噪声模型（mu=0, var=0.02）
            img = getControlImage(app, {'originImage_2','original_image_2','original_image','original_image_2'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end

            mu = 0;
            var = 0.02;
            try
                noisy = imnoise(img, 'gaussian', mu, var);
            catch
                % fallback: operate on double in [0,1]
                I = im2double(img);
                noise = mu + sqrt(var) * randn(size(I));
                noisy = im2uint8(max(0, min(1, I + noise)));
            end

            setControlImage(app, {'degradedImage_2','degradedImage2','degraded_image_2'}, noisy);
            try app.degradedImage_2.UserData = noisy; end

            % 绘制直方图到 PSFImage_2 以便观察噪声分布
            try
                if isprop(app,'PSFImage_2') && ~isempty(app.PSFImage_2)
                    [counts, centers] = imhist(noisy);
                    bar(app.PSFImage_2, centers, counts);
                    title(app.PSFImage_2, '噪声图直方图');
                    xlabel(app.PSFImage_2, '灰度值'); ylabel(app.PSFImage_2, '像素数');
                end
            catch
            end
        end

        % Button pushed function: uniformly
        function uniformlyButtonPushed(app, event)
            % 均匀分布噪声 (a=-30, b=30)
            img = getControlImage(app, {'originImage_2','original_image_2','original_image','original_image_2'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end

            a = -30; b = 30;
            [M, N] = size(img);
            noise_uniform = a + (b - a) * rand(M, N);
            noisy = double(img) + noise_uniform;
            noisy = uint8(min(max(noisy, 0), 255));

            setControlImage(app, {'degradedImage_2','degradedImage2','degraded_image_2'}, noisy);
            try app.degradedImage_2.UserData = noisy; end

            try
                if isprop(app,'PSFImage_2') && ~isempty(app.PSFImage_2)
                    [counts, centers] = imhist(noisy);
                    bar(app.PSFImage_2, centers, counts);
                    title(app.PSFImage_2, '均匀噪声直方图');
                    xlabel(app.PSFImage_2, '灰度值'); ylabel(app.PSFImage_2, '像素数');
                end
            catch
            end
        end

        % Button pushed function: salt
        function saltButtonPushed(app, event)
            % 椒盐噪声 (density = 0.05)
            img = getControlImage(app, {'originImage_2','original_image_2','original_image','original_image_2'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end

            density = 0.05;
            noisy = imnoise(img, 'salt & pepper', density);

            setControlImage(app, {'degradedImage_2','degradedImage2','degraded_image_2'}, noisy);
            try app.degradedImage_2.UserData = noisy; end

            try
                if isprop(app,'PSFImage_2') && ~isempty(app.PSFImage_2)
                    [counts, centers] = imhist(noisy);
                    bar(app.PSFImage_2, centers, counts);
                    title(app.PSFImage_2, '椒盐噪声直方图');
                    xlabel(app.PSFImage_2, '灰度值'); ylabel(app.PSFImage_2, '像素数');
                end
            catch
            end
        end

        % Value changed function: topbar_color
        function topbar_colorValueChanged(app, event)
             value = app.topbar_color.Value;
             %互斥逻辑
             app.topbar_Pseudocolor.Value = false;
             app.topbar_Full_color.Value = false;
             %顶部导航栏逻辑
             app.color.Visible = true;
             app.Pseudocolor.Visible = false;
             app.Full_color.Visible = false;
        end

        % Value changed function: topbar_Pseudocolor
        function topbar_PseudocolorValueChanged(app, event)
             value = app.topbar_Pseudocolor.Value;
             %互斥逻辑
             app.topbar_color.Value = false;
             app.topbar_Full_color.Value = false;
             %顶部导航栏逻辑
             app.color.Visible = false;
             app.Pseudocolor.Visible = true;
             app.Full_color.Visible = false;
        end

        % Value changed function: topbar_Full_color
        function topbar_Full_colorValueChanged(app, event)
             value = app.topbar_Full_color.Value;
             %互斥逻辑
             app.topbar_color.Value = false;
             app.topbar_Pseudocolor.Value = false;
             %顶部导航栏逻辑
             app.color.Visible = false;
             app.Pseudocolor.Visible = false;
             app.Full_color.Visible = true;
        end

        % Value changed function: topbar_Huffman
        function topbar_HuffmanValueChanged(app, event)
            value = app.topbar_Huffman.Value;
            %互斥逻辑
            app.topbar_Predictive.Value = false;
            %顶部导航栏逻辑
            app.Huffman.Visible = true;
            app.Predictive.Visible = false;
        end

        % Value changed function: topbar_Predictive
        function topbar_PredictiveValueChanged(app, event)
            value = app.topbar_Predictive.Value;
            %互斥逻辑
            app.topbar_Huffman.Value = false;
            %顶部导航栏逻辑
            app.Huffman.Visible = false;
            app.Predictive.Visible = true;
        end

        % Button pushed function: Meansalt
        function MeansaltButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_3','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 添加椒盐噪声
            density_sp = 0.03;
            noisy = imnoise(img, 'salt & pepper', density_sp);
            setControlImage(app, {'originImage_3'}, noisy);
            
            % 获取选择的滤波器类型
            filterType = app.stencil_8.Value;
            
            noisy_double = double(noisy);
            window_size = 3;
            
            switch filterType
                case '算术均值滤波'
                    % 使用 imfilter 进行算术均值滤波
                    h = fspecial('average', [window_size window_size]);
                    result = imfilter(noisy_double, h, 'replicate');
                    
                case '几何均值滤波'
                    % 几何均值滤波 - 使用滑动窗口向量化
                    noisy_safe = noisy_double;
                    noisy_safe(noisy_safe == 0) = 1;
                    log_img = log(noisy_safe);
                    h = ones(window_size) / (window_size^2);
                    result = exp(imfilter(log_img, h, 'replicate'));
                    
                case '逆谐波均值滤波(Q=1.5)'
                    % 逆谐波均值滤波 Q=1.5 (适合处理盐噪声)
                    Q = 1.5;
                    noisy_safe = noisy_double;
                    noisy_safe(noisy_safe == 0) = 0.001;
                    h = ones(window_size);
                    numerator = imfilter(noisy_safe.^(Q+1), h, 'replicate');
                    denominator = imfilter(noisy_safe.^Q, h, 'replicate');
                    result = numerator ./ (denominator + eps);
                    
                case '逆谐波均值滤波(Q=-1.5)'
                    % 逆谐波均值滤波 Q=-1.5 (适合处理椒噪声)
                    Q = -1.5;
                    noisy_safe = noisy_double;
                    noisy_safe(noisy_safe == 0) = 0.001;
                    h = ones(window_size);
                    numerator = imfilter(noisy_safe.^(Q+1), h, 'replicate');
                    denominator = imfilter(noisy_safe.^Q, h, 'replicate');
                    result = numerator ./ (denominator + eps);
                    
                case '逆谐波滤波'
                    % 逆谐波均值滤波 Q=1.5
                    Q = 1.5;
                    h = ones(window_size);
                    numerator = imfilter(noisy_double.^(Q+1), h, 'replicate');
                    denominator = imfilter(noisy_double.^Q, h, 'replicate');
                    result = numerator ./ (denominator + eps);
                otherwise
                    % 默认使用算术均值滤波
                    h = fspecial('average', [window_size window_size]);
                    result = imfilter(noisy_double, h, 'replicate');
            end
            
            result = uint8(result);
            setControlImage(app, {'plural_images'}, result);
        end

        % Button pushed function: Sequentialsalt
        function SequentialsaltButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_3','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 添加椒盐噪声
            density_sp = 0.03;
            noisy = imnoise(img, 'salt & pepper', density_sp);
            setControlImage(app, {'originImage_3'}, noisy);
            
            % 获取选择的滤波器类型
            filterType = app.stencil_7.Value;
            
            window_size = 3;
            domain = ones(window_size);
            
            switch filterType
                case '中值滤波'
                    % 使用 MATLAB 内置的中值滤波
                    result = medfilt2(noisy, [window_size window_size]);
                    
                case '最大值滤波'
                    % 使用 ordfilt2 进行最大值滤波
                    result = ordfilt2(noisy, window_size^2, domain);
                    
                case '最小值滤波'
                    % 使用 ordfilt2 进行最小值滤波
                    result = ordfilt2(noisy, 1, domain);
                    
                case '中点滤波'
                    % 中点滤波 = (最大值 + 最小值) / 2
                    max_img = ordfilt2(noisy, window_size^2, domain);
                    min_img = ordfilt2(noisy, 1, domain);
                    result = uint8((double(max_img) + double(min_img)) / 2);
                    
                case '阿尔法均值滤波'
                    % 阿尔法修剪均值滤波
                    d = 2; % 修剪掉最大和最小各1个值
                    total = window_size^2;
                    trimmed_sum = zeros(size(noisy));
                    % 累加中间的值(排除最小的d/2个和最大的d/2个)
                    for order = (d/2 + 1):(total - d/2)
                        trimmed_sum = trimmed_sum + double(ordfilt2(noisy, order, domain));
                    end
                    result = uint8(trimmed_sum / (total - d));
            end
            
            setControlImage(app, {'plural_images'}, result);
        end

        % Button pushed function: MeanGaussian
        function MeanGaussianButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_3','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 添加高斯噪声
            var_gaussian = 0.02;
            noisy = imnoise(img, 'gaussian', 0, var_gaussian);
            setControlImage(app, {'originImage_3'}, noisy);
            
            % 获取选择的滤波器类型
            filterType = app.stencil_6.Value;
            
            noisy_double = double(noisy);
            window_size = 3;
            
            switch filterType
                case '算术均值滤波'
                    % 使用 imfilter 进行算术均值滤波
                    h = fspecial('average', [window_size window_size]);
                    result = imfilter(noisy_double, h, 'replicate');
                    
                case '几何均值滤波'
                    % 几何均值滤波 - 使用向量化
                    noisy_safe = noisy_double;
                    noisy_safe(noisy_safe == 0) = 1;
                    log_img = log(noisy_safe);
                    h = ones(window_size) / (window_size^2);
                    result = exp(imfilter(log_img, h, 'replicate'));
                    
                case '逆逆谐波均值滤波(Q=1.5)'
                    % 逆谐波均值滤波 Q=1.5 (适合处理盐噪声)
                    Q = 1.5;
                    noisy_safe = noisy_double;
                    noisy_safe(noisy_safe == 0) = 0.001;
                    h = ones(window_size);
                    numerator = imfilter(noisy_safe.^(Q+1), h, 'replicate');
                    denominator = imfilter(noisy_safe.^Q, h, 'replicate');
                    result = numerator ./ (denominator + eps);
                    
                case '谐波均值滤波(Q=-1.5)'
                    % 逆谐波均值滤波 Q=-1.5 (适合处理椒噪声)
                    Q = -1.5;
                    noisy_safe = noisy_double;
                    noisy_safe(noisy_safe == 0) = 0.001;
                    h = ones(window_size);
                    numerator = imfilter(noisy_safe.^(Q+1), h, 'replicate');
                    denominator = imfilter(noisy_safe.^Q, h, 'replicate');
                    result = numerator ./ (denominator + eps);
                    
                case '逆谐波滤波'
                    % 逆谐波均值滤波 Q=0.5
                    Q = 0.5;
                    h = ones(window_size);
                    numerator = imfilter(noisy_double.^(Q+1), h, 'replicate');
                    denominator = imfilter(noisy_double.^Q, h, 'replicate');
                    result = numerator ./ (denominator + eps);
                otherwise
                    % 如果没有匹配的滤波器,使用原图
                    result = noisy_double;
            end
            
            result = uint8(result);
            setControlImage(app, {'plural_images'}, result);
        end

        % Button pushed function: SequentialGaussian
        function SequentialGaussianButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_3','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 添加高斯噪声
            var_gaussian = 0.02;
            noisy = imnoise(img, 'gaussian', 0, var_gaussian);
            setControlImage(app, {'originImage_3'}, noisy);
            
            % 获取选择的滤波器类型
            filterType = app.stencil_5.Value;
            
            window_size = 3;
            domain = ones(window_size);
            
            switch filterType
                case '中值滤波'
                    % 使用 MATLAB 内置的中值滤波
                    result = medfilt2(noisy, [window_size window_size]);
                    
                case '最大值滤波'
                    % 使用 ordfilt2 进行最大值滤波
                    result = ordfilt2(noisy, window_size^2, domain);
                    
                case '最小值滤波'
                    % 使用 ordfilt2 进行最小值滤波
                    result = ordfilt2(noisy, 1, domain);
                    
                case '中点滤波'
                    % 中点滤波 = (最大值 + 最小值) / 2
                    max_img = ordfilt2(noisy, window_size^2, domain);
                    min_img = ordfilt2(noisy, 1, domain);
                    result = uint8((double(max_img) + double(min_img)) / 2);
                    
                case '阿尔法均值滤波'
                    % 阿尔法修剪均值滤波
                    d = 2; % 修剪掉最大和最小各1个值
                    total = window_size^2;
                    trimmed_sum = zeros(size(noisy));
                    % 累加中间的值(排除最小的d/2个和最大的d/2个)
                    for order = (d/2 + 1):(total - d/2)
                        trimmed_sum = trimmed_sum + double(ordfilt2(noisy, order, domain));
                    end
                    result = uint8(trimmed_sum / (total - d));
            end
            
            setControlImage(app, {'plural_images'}, result);
        end

        % Button pushed function: mixedbutton
        function mixedbuttonButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_3','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 添加混合噪声(高斯+椒盐)
            noisy = imnoise(img, 'gaussian', 0, 0.02);
            noisy = imnoise(noisy, 'salt & pepper', 0.02);
            setControlImage(app, {'originImage_3'}, noisy);
            
            % 获取选择的滤波器类型
            filterType = app.stencil_4.Value;
            
            window_size = 3;
            domain = ones(window_size);
            
            switch filterType
                case '中值滤波'
                    % 使用 MATLAB 内置的中值滤波
                    result = medfilt2(noisy, [window_size window_size]);
                    
                case '阿尔法均值滤波'
                    % 阿尔法修剪均值滤波 - 使用 ordfilt2 优化
                    d = 2; % 修剪掉最大和最小各1个值
                    total = window_size^2;
                    trimmed_sum = zeros(size(noisy));
                    % 累加中间的值(排除最小的d/2个和最大的d/2个)
                    for order = (d/2 + 1):(total - d/2)
                        trimmed_sum = trimmed_sum + double(ordfilt2(noisy, order, domain));
                    end
                    result = uint8(trimmed_sum / (total - d));
            end
            
            setControlImage(app, {'plural_images'}, result);
        end

        % Button pushed function: Inversebutton
        function InversebuttonButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_4','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 模拟运动模糊退化
            LEN = 25;
            THETA = 11;
            PSF = fspecial('motion', LEN, THETA);
            Blurred = imfilter(img, PSF, 'circular', 'conv');
            
            % 添加噪声（模拟实际情况）
            noise_var = 0.001;
            Blurred_noisy = imnoise(Blurred, 'gaussian', 0, noise_var);
            
            % 显示退化后的图像到原始图像位置
            setControlImage(app, {'originImage_4'}, Blurred_noisy);
            
            % 获取选择的逆滤波方法
            filterType = app.stencil_9.Value;
            
            H = psf2otf(PSF, size(Blurred_noisy));
            F_blurred = fft2(Blurred_noisy);
            
            switch filterType
                case '传统逆滤波'
                    % 传统逆滤波 - 只取幅度
                    H_abs = abs(H);
                    F_hat_inv = F_blurred ./ (H_abs + eps);
                    result = real(ifft2(F_hat_inv));
                    result = mat2gray(result);
                    
                case '阈值法改进逆滤波'
                    % 阈值处理法 - 避免除以接近零的值
                    threshold = 0.05;
                    H_mag = abs(H);
                    H_reg = H;
                    % 小于阈值的设为阈值，保持相位信息
                    small_idx = H_mag < threshold;
                    H_reg(small_idx) = threshold .* exp(1i * angle(H(small_idx)));
                    
                    F_hat_inv = F_blurred ./ H_reg;
                    result = real(ifft2(F_hat_inv));
                    result = mat2gray(result);
                    
                case '频率限制法逆滤波'
                    % 限幅逆滤波 - 只在|H|足够大的频率上进行逆滤波
                    limit_factor = 0.05;
                    H_mag = abs(H);
                    F_hat_inv = zeros(size(F_blurred));
                    
                    % 只在|H|足够大的频率上进行逆滤波
                    valid_idx = H_mag > limit_factor * max(H_mag(:));
                    F_hat_inv(valid_idx) = F_blurred(valid_idx) ./ H(valid_idx);
                    
                    result = real(ifft2(F_hat_inv));
                    result = mat2gray(result);
                    
                case '正则化法逆滤波'
                    % 正则化逆滤波 - Tikhonov正则化
                    alpha = 0.02;
                    H_conj = conj(H);
                    H_abs2 = abs(H).^2;
                    F_hat_inv = (H_conj ./ (H_abs2 + alpha)) .* F_blurred;
                    
                    result = real(ifft2(F_hat_inv));
                    result = mat2gray(result);
            end
            
            % 转换回uint8并显示
            result = uint8(result * 255);
            setControlImage(app, {'plural_images_2'}, result);
        end

        % Button pushed function: Wienerbutton
        function WienerbuttonButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_4','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 模拟运动模糊退化
            LEN = 25;
            THETA = 11;
            PSF = fspecial('motion', LEN, THETA);
            Blurred = imfilter(img, PSF, 'circular', 'conv');
            
            % 添加噪声（模拟实际情况）
            noise_var = 0.001;
            Blurred_noisy = imnoise(Blurred, 'gaussian', 0, noise_var);
            
            % 显示退化后的图像到原始图像位置
            setControlImage(app, {'originImage_4'}, Blurred_noisy);
            
            % 维纳滤波复原
            H = psf2otf(PSF, size(Blurred_noisy));
            F_blurred = fft2(Blurred_noisy);
            
            % 使用维纳滤波公式: F_hat = [1/H * (|H|^2) / (|H|^2 + K)] * G
            K = 0.01; % 可调参数，根据噪声程度调整
            H_abs2 = abs(H).^2;
            F_hat_wiener = (1 ./ (H + eps)) .* (H_abs2 ./ (H_abs2 + K)) .* F_blurred;
            
            result = real(ifft2(F_hat_wiener));
            result = mat2gray(result);
            
            % 转换回uint8并显示
            result = uint8(result * 255);
            setControlImage(app, {'plural_images_2'}, result);
        end

        % Button pushed function: projection
        function projectionButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_5','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 显示原始图像
            setControlImage(app, {'originImage_5'}, img);
            
            % 设置投影角度
            theta = 0:179;
            
            % 执行Radon变换
            [R, rho] = radon(img, theta);
            
            % PSFImage_3: 显示选中滤波器的重建结果
            axes(app.PSFImage_3);
            cla;
            
            % 获取选择的滤波器类型
            filterType = app.model_2.Value;
            
            % 映射滤波器名称
            switch filterType
                case 'ram-lak滤波器'
                    filter_name = 'ram-lak';
                case 'shepp-logan滤波器'
                    filter_name = 'shepp-logan';
                case 'cosine滤波器'
                    filter_name = 'cosine';
                case 'hamming滤波器'
                    filter_name = 'hamming';
                case 'hann滤波器'
                    filter_name = 'hann';
                otherwise
                    filter_name = 'ram-lak';
            end
            
            % 执行反投影重建
            try
                reconstructed = iradon(R, theta, 'linear', filter_name);
                
                % 显示重建结果 - 使用固定的显示范围以便比较不同滤波器
                % 转换为uint8以保持一致的显示效果
                recon_display = mat2gray(reconstructed);
                recon_uint8 = im2uint8(recon_display);
                
                % 在PSFImage_3上显示重建结果
                imshow(recon_uint8);
                title(sprintf('反投影重建 (%s)', filterType), 'Color', 'w');
                set(gca, 'Color', [0.1 0.1 0.1]);
                
                % 显示重建质量信息
                try
                    % 计算重建图像的统计信息
                    recon_mean = mean(reconstructed(:));
                    recon_std = std(reconstructed(:));
                    xlabel(sprintf('均值: %.2f, 标准差: %.2f', recon_mean, recon_std), 'Color', 'w');
                catch
                end
                
            catch ME
                uialert(app.UIFigure, ['反投影重建失败: ' ME.message], '错误');
            end
            
            % PSFImage_4: 显示投影数据（投影曲线,不是正弦图）
            axes(app.PSFImage_4);
            cla;
            
            % 选择几个代表性角度显示投影曲线
            display_angles = [0, 30, 60, 90];
            colors = {'r', 'g', 'b', 'm'};
            
            hold on;
            for i = 1:length(display_angles)
                angle = display_angles(i);
                if angle <= 179
                    angle_idx = angle + 1;
                    projection = R(:, angle_idx);
                    color_idx = mod(i-1, length(colors)) + 1;
                    plot(rho, projection, colors{color_idx}, 'LineWidth', 1.5, ...
                         'DisplayName', sprintf('%d°', angle));
                end
            end
            hold off;
            
            xlabel('距离 ρ', 'Color', 'w');
            ylabel('投影值', 'Color', 'w');
            title('不同角度的投影曲线', 'Color', 'w');
            legend('show', 'Location', 'best', 'TextColor', 'w');
            grid on;
            set(gca, 'Color', [0.1 0.1 0.1], 'XColor', 'w', 'YColor', 'w');
            axis tight;
        end

        % Button pushed function: Fourier
        function FourierButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_5','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 显示原始图像
            setControlImage(app, {'originImage_5'}, img);
            
            % 获取角度值（单值滑块）
            angle_value = round(app.degree_1.Value);
            
            % 设置要显示的角度（选定角度附近的4个角度）
            angle_offset = 10; % 每个角度间隔10度
            theta = [max(0, angle_value - 30), ...
                     max(0, angle_value - 10), ...
                     min(180, angle_value + 10), ...
                     min(180, angle_value + 30)];
            theta = unique(theta); % 去除重复值
            
            % 计算2D傅里叶变换
            F = fft2(double(img));
            F_shifted = fftshift(F);
            
            % PSFImage_3: 显示选定角度的投影曲线(单条)
            axes(app.PSFImage_3);
            cla;
            
            % 只计算选定角度的投影
            [R, rho] = radon(img, angle_value);
            
            plot(rho, R, 'b', 'LineWidth', 2);
            xlabel('距离 ρ', 'Color', 'w');
            ylabel('投影值', 'Color', 'w');
            title(sprintf('%d°投影', angle_value), 'Color', 'w');
            grid on;
            set(gca, 'Color', [0.1 0.1 0.1], 'XColor', 'w', 'YColor', 'w');
            
            % PSFImage_4: 显示该角度投影的1D傅里叶变换
            axes(app.PSFImage_4);
            cla;
            
            % 计算投影的1D傅里叶变换
            R_fft = fft(R);
            R_fft_shifted = fftshift(R_fft);
            R_fft_magnitude = log(1 + abs(R_fft_shifted));
            
            freq = linspace(-0.5, 0.5, length(R_fft_magnitude));
            plot(freq, R_fft_magnitude, 'b', 'LineWidth', 2);
            
            xlabel('归一化频率', 'Color', 'w');
            ylabel('对数幅度', 'Color', 'w');
            title(sprintf('%d°投影的1D傅里叶变换', angle_value), 'Color', 'w');
            grid on;
            set(gca, 'Color', [0.1 0.1 0.1], 'XColor', 'w', 'YColor', 'w');
        end

        % Button pushed function: Radon
        function RadonButtonPushed(app, event)
            % 获取原始图像
            img = getControlImage(app, {'originImage_5','originImage_2','original_image'});
            if isempty(img), uialert(app.UIFigure,'请先加载图片','错误'); return; end
            if size(img,3)==3, img = rgb2gray(img); end
            
            % 显示原始图像
            setControlImage(app, {'originImage_5'}, img);
            
            % 获取角度值（单值滑块,用于选择显示哪些投影曲线）
            angle_value = round(app.degree.Value);
            
            % 设置投影角度范围（完整范围 0-179度）
            theta = 0:179;
            
            % 执行Radon变换
            [R, rho] = radon(img, theta);
            
            % PSFImage_3: 显示每个度数的投影图(灰度条纹)
            axes(app.PSFImage_3);
            cla;
            
            % 选择4个角度进行显示
            selected_angles = [max(0, angle_value - 30), ...
                              max(0, angle_value - 10), ...
                              min(179, angle_value + 10), ...
                              min(179, angle_value + 30)];
            selected_angles = unique(selected_angles);
            
            % 提取这4个角度的投影并拼接成图像显示
            projection_images = [];
            for i = 1:length(selected_angles)
                angle_idx = selected_angles(i) + 1;
                if angle_idx <= length(theta)
                    % 获取投影列并转置成行,归一化
                    proj_column = R(:, angle_idx);
                    proj_norm = mat2gray(proj_column);
                    % 将列向量转为图像(作为一列显示)
                    projection_images = [projection_images, repmat(proj_norm, 1, 50)];
                end
            end
            
            imagesc(projection_images);
            axis xy;
            colormap(gca, gray);
            colorbar;
            ylabel('投影值 (×10⁴)', 'Color', 'w');
            title(sprintf('各角度投影图 (中心: %d°)', angle_value), 'Color', 'w');
            set(gca, 'XColor', 'w', 'YColor', 'w', 'Color', [0.1 0.1 0.1]);
            
            % 设置X轴刻度标签为角度
            num_strips = length(selected_angles);
            xtick_positions = linspace(25, size(projection_images,2)-25, num_strips);
            set(gca, 'XTick', xtick_positions);
            xticklabels_str = cell(1, num_strips);
            for i = 1:num_strips
                xticklabels_str{i} = sprintf('%d°投影图', selected_angles(i));
            end
            set(gca, 'XTickLabel', xticklabels_str);
            
            axis tight;  % 紧凑显示,去除多余空白
            
            % PSFImage_4: 显示不同角度的投影曲线
            axes(app.PSFImage_4);
            cla;
            
            colors = {'r', 'g', 'b', 'm'};
            
            hold on;
            for i = 1:length(selected_angles)
                angle_idx = selected_angles(i) + 1;
                if angle_idx <= length(theta)
                    color_idx = mod(i-1, length(colors)) + 1;
                    projection = R(:, angle_idx);
                    plot(rho, projection, colors{color_idx}, 'LineWidth', 1.5, ...
                         'DisplayName', sprintf('%d° (max=%.1f)', selected_angles(i), max(projection)));
                end
            end
            hold off;
            
            xlabel('距离 ρ', 'Color', 'w');
            ylabel('投影值', 'Color', 'w');
            title(sprintf('不同角度的投影曲线 (中心: %d°)', angle_value), 'Color', 'w');
            legend('show', 'Location', 'best', 'TextColor', 'w');
            grid on;
            set(gca, 'Color', [0.1 0.1 0.1], 'XColor', 'w', 'YColor', 'w');
            axis tight;
        end

        % Button pushed function: RGB
        function RGBButtonPushed(app, event)
            % 获取原始彩色图像
            img = getControlImage(app, {'originImage_6','original_image'});
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); 
                return; 
            end
            
            % 确保是RGB图像
            if size(img,3) ~= 3
                uialert(app.UIFigure,'请加载RGB彩色图像(3通道)','错误'); 
                return;
            end
            
            % 显示原始图像
            setControlImage(app, {'originImage_6'}, img);
            
            % 预分配内存,一次性创建所有图像
            [h, w, ~] = size(img);
            
            % 上面三幅图:RGB三通道图片(预分配zeros数组)
            red_channel_img = zeros(h, w, 3, 'uint8');
            red_channel_img(:,:,1) = img(:,:,1);
            
            green_channel_img = zeros(h, w, 3, 'uint8');
            green_channel_img(:,:,2) = img(:,:,2);
            
            blue_channel_img = zeros(h, w, 3, 'uint8');
            blue_channel_img(:,:,3) = img(:,:,3);
            
            % 批量设置图像(减少函数调用次数)
            try
                app.HImage.ImageSource = red_channel_img;
                app.HImage.UserData = red_channel_img;
            catch
                setControlImage(app, {'HImage'}, red_channel_img);
            end
            
            try
                app.SImage.ImageSource = green_channel_img;
                app.SImage.UserData = green_channel_img;
            catch
                setControlImage(app, {'SImage'}, green_channel_img);
            end
            
            try
                app.IImage.ImageSource = blue_channel_img;
                app.IImage.UserData = blue_channel_img;
            catch
                setControlImage(app, {'IImage'}, blue_channel_img);
            end
            
            % 下面三幅图:红绿蓝灰度图(直接提取,不复制)
            try
                app.colorImage.ImageSource = img(:,:,1);
                app.colorImage.UserData = img(:,:,1);
            catch
                setControlImage(app, {'colorImage'}, img(:,:,1));
            end
            
            try
                app.enhanceImage.ImageSource = img(:,:,2);
                app.enhanceImage.UserData = img(:,:,2);
            catch
                setControlImage(app, {'enhanceImage'}, img(:,:,2));
            end
            
            try
                app.lightImage.ImageSource = img(:,:,3);
                app.lightImage.UserData = img(:,:,3);
            catch
                setControlImage(app, {'lightImage'}, img(:,:,3));
            end
        end

        % Button pushed function: HSI
        function HSIButtonPushed(app, event)
            % 获取原始彩色图像
            img = getControlImage(app, {'originImage_6','original_image'});
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); 
                return; 
            end
            
            % 确保是RGB图像
            if size(img,3) ~= 3
                uialert(app.UIFigure,'请加载RGB彩色图像(3通道)','错误'); 
                return;
            end
            
            % 显示原始图像
            setControlImage(app, {'originImage_6'}, img);
            
            % 转换为double类型
            rgb_double = im2double(img);
            R = rgb_double(:,:,1);
            G = rgb_double(:,:,2);
            B = rgb_double(:,:,3);
            
            %% RGB到HSI转换
            % 计算强度I
            I = (R + G + B) / 3;
            
            % 计算饱和度S
            min_RGB = min(min(R, G), B);
            S = 1 - 3./(R + G + B + eps) .* min_RGB;
            S(R + G + B == 0) = 0;
            
            % 计算色调H
            num = 0.5 * ((R - G) + (R - B));
            den = sqrt((R - G).^2 + (R - B).*(G - B) + eps);
            theta = acos(num./(den + eps));
            
            H = theta;
            H(G < B) = 2*pi - theta(G < B);
            H = H / (2*pi);  % 归一化到[0,1]
            
            % 上面三幅图:HSI三分量图
            % H分量(使用伪彩色显示)
            H_display = ind2rgb(gray2ind(mat2gray(H), 256), hsv(256));
            H_display_uint8 = im2uint8(H_display);
            
            % S和I分量转换为uint8
            S_uint8 = im2uint8(S);
            I_uint8 = im2uint8(I);
            
            % 批量设置HSI分量图像
            try
                app.HImage.ImageSource = H_display_uint8;
                app.HImage.UserData = H_display_uint8;
            catch
                setControlImage(app, {'HImage'}, H_display_uint8);
            end
            
            try
                app.SImage.ImageSource = S_uint8;
                app.SImage.UserData = S_uint8;
            catch
                setControlImage(app, {'SImage'}, S_uint8);
            end
            
            try
                app.IImage.ImageSource = I_uint8;
                app.IImage.UserData = I_uint8;
            catch
                setControlImage(app, {'IImage'}, I_uint8);
            end
            
            % 下面三幅图:饱和度增强、色调旋转、亮度降低
            % 1. 饱和度增强 (S×1.5)
            S_enhanced = min(S * 1.5, 1);
            rgb_s_enhanced = app.hsi2rgb_app(H, S_enhanced, I);
            rgb_s_enhanced_uint8 = im2uint8(rgb_s_enhanced);
            
            % 2. 色调旋转 (+72度,即+0.2)
            H_shifted = mod(H + 0.2, 1);
            rgb_h_shifted = app.hsi2rgb_app(H_shifted, S, I);
            rgb_h_shifted_uint8 = im2uint8(rgb_h_shifted);
            
            % 3. 亮度降低 (I×0.7)
            I_darkened = I * 0.7;
            rgb_i_darkened = app.hsi2rgb_app(H, S, I_darkened);
            rgb_i_darkened_uint8 = im2uint8(rgb_i_darkened);
            
            % 批量设置变换后的图像
            try
                app.colorImage.ImageSource = rgb_s_enhanced_uint8;
                app.colorImage.UserData = rgb_s_enhanced_uint8;
            catch
                setControlImage(app, {'colorImage'}, rgb_s_enhanced_uint8);
            end
            
            try
                app.enhanceImage.ImageSource = rgb_h_shifted_uint8;
                app.enhanceImage.UserData = rgb_h_shifted_uint8;
            catch
                setControlImage(app, {'enhanceImage'}, rgb_h_shifted_uint8);
            end
            
            try
                app.lightImage.ImageSource = rgb_i_darkened_uint8;
                app.lightImage.UserData = rgb_i_darkened_uint8;
            catch
                setControlImage(app, {'lightImage'}, rgb_i_darkened_uint8);
            end
        end

        % Button pushed function: Pseudocolorbutton
        function PseudocolorbuttonButtonPushed(app, event)
            
            % 获取原始灰度图像
            img = getControlImage(app, {'originImage_7','originImage_2','original_image'});
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); 
                return; 
            end
            
            % 确保是灰度图像
            if size(img,3) == 3
                img = rgb2gray(img);
            end
            
            % 显示原始灰度图像
            setControlImage(app, {'originImage_7'}, img);
            
            % 调用 gray2pseudo_hsi 函数转换为伪彩色
            try
                pseudo_color = gray2pseudo_hsi(img);
                
                % 转换为uint8类型用于显示
                if isfloat(pseudo_color)
                    pseudo_color = im2uint8(pseudo_color);
                end
                
                % 直接设置图像源以提高性能
                try
                    app.PseudocolorImage.ImageSource = pseudo_color;
                    app.PseudocolorImage.UserData = pseudo_color;
                catch
                    setControlImage(app, {'PseudocolorImage'}, pseudo_color);
                end
                
            catch ME
                uialert(app.UIFigure, ['伪彩色转换失败: ' ME.message], '错误');
            end
        end

        % Button pushed function: RGBbutton
        function RGBbuttonButtonPushed(app, event)

            % 获取原始RGB图像
            img = getControlImage(app, {'originImage_8','originImage_6','original_image'});
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); 
                return; 
            end
            
            % 确保是RGB图像
            if size(img,3) ~= 3
                uialert(app.UIFigure,'请加载RGB彩色图像(3通道)','错误'); 
                return;
            end
            
            % 确保是uint8类型(process_rgb_channels要求)
            if ~isa(img, 'uint8')
                img = im2uint8(img);
            end
            
            % 显示原始图像
            setControlImage(app, {'originImage_8'}, img);
            
            % 调用 process_rgb_channels 函数处理
            try
                rgb_processed = process_rgb_channels(img);
                
                % 确保输出是uint8
                if ~isa(rgb_processed, 'uint8')
                    rgb_processed = im2uint8(rgb_processed);
                end
                
                % 直接设置图像源以提高性能
                try
                    app.Full_colorImage.ImageSource = rgb_processed;
                    app.Full_colorImage.UserData = rgb_processed;
                catch
                    setControlImage(app, {'Full_colorImage'}, rgb_processed);
                end
                
            catch ME
                uialert(app.UIFigure, ['RGB处理失败: ' ME.message], '错误');
            end
        end

        % Button pushed function: HSIbutton
        function HSIbuttonButtonPushed(app, event)

            % 获取原始RGB图像
            img = getControlImage(app, {'originImage_8','originImage_6','original_image'});
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); 
                return; 
            end
            
            % 确保是RGB图像
            if size(img,3) ~= 3
                uialert(app.UIFigure,'请加载RGB彩色图像(3通道)','错误'); 
                return;
            end
            
            % 确保是uint8类型(process_hsi_intensity要求)
            if ~isa(img, 'uint8')
                img = im2uint8(img);
            end
            
            % 显示原始图像
            setControlImage(app, {'originImage_8'}, img);
            
            % 调用 process_hsi_intensity 函数处理I分量
            try
                hsi_processed = process_hsi_intensity(img);
                
                % 转换输出为uint8
                if isfloat(hsi_processed)
                    hsi_processed = im2uint8(hsi_processed);
                end
                
                % 直接设置图像源以提高性能
                try
                    app.Full_colorImage.ImageSource = hsi_processed;
                    app.Full_colorImage.UserData = hsi_processed;
                catch
                    setControlImage(app, {'Full_colorImage'}, hsi_processed);
                end
                
            catch ME
                uialert(app.UIFigure, ['HSI处理失败: ' ME.message], '错误');
            end
        end

        % Button pushed function: Huffmanbutton
        function HuffmanbuttonButtonPushed(app, event)

            % 获取原始灰度图像
            img = getControlImage(app, {'originImage_9','originImage_2','original_image'});
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); 
                return; 
            end
            
            % 确保是灰度图像
            if size(img,3) == 3
                img = rgb2gray(img);
            end
            
            % 直接设置ImageSource(优化性能,避免文件I/O)
            try
                app.originImage_9.ImageSource = img;
                app.originImage_9.UserData = img;
            catch
                setControlImage(app, {'originImage_9'}, img);
            end
            
            % Huffman 编码 (可能需要时间,显示进度)
            try
                % 如果图像太大,先缩小
                [h, w] = size(img);
                was_resized = false;
                if h * w > 512 * 512
                    scale = sqrt((512*512) / (h*w));
                    img = imresize(img, scale);
                    was_resized = true;
                    fprintf('图像已缩放至 %dx%d 以加快处理\n', size(img,1), size(img,2));
                end
                
                H = mat2huff(img);
                
                % 跳过解码验证以加快速度(只编码,不解码)
                % G_rec = huff2mat(H);
                % ok_huff = isequal(img, G_rec);
                
                % 计算压缩比
                bits_raw = numel(img) * 8;
                bits_huff = numel(H.bitstream);
                ratio_huff = bits_raw / bits_huff;
                
                % 生成HTML报告(简化版本,不验证正确性)
                resize_info = '';
                if was_resized
                    resize_info = sprintf('<tr><td colspan="2" style="color:#f48771;font-size:0.9em;">* 图像已缩放至 %dx%d</td></tr>', size(img,1), size(img,2));
                end
                
                html_content = sprintf(['<html><body style="background-color:#1c1c1c;color:#b0b0b0;font-family:Consolas,monospace;padding:10px;">' ...
                    '<h3 style="color:#4ec9b0;">霍夫曼编码结果</h3>' ...
                    '<table style="color:#b0b0b0;">' ...
                    '<tr><td><b>原始比特数:</b></td><td style="text-align:right;">%d bits</td></tr>' ...
                    '<tr><td><b>编码后比特数:</b></td><td style="text-align:right;">%d bits</td></tr>' ...
                    '<tr><td><b>压缩比:</b></td><td style="text-align:right;color:#4ec9b0;"><b>%.3f</b></td></tr>' ...
                    '<tr><td><b>节省空间:</b></td><td style="text-align:right;">%.2f%%</td></tr>' ...
                    '%s' ...
                    '</table></body></html>'], ...
                    bits_raw, bits_huff, ratio_huff, ...
                    (1 - bits_huff/bits_raw) * 100, ...
                    resize_info);
                
                % 显示HTML内容
                try
                    app.HTML.HTMLSource = html_content;
                catch
                    % 如果HTML组件不可用,使用fprintf输出
                    fprintf('霍夫曼编码结果:\n原始: %d bits\n编码: %d bits\n压缩比: %.3f\n节省: %.2f%%\n', ...
                        bits_raw, bits_huff, ratio_huff, (1 - bits_huff/bits_raw) * 100);
                end
                
            catch ME
                uialert(app.UIFigure, ['Huffman编码失败: ' ME.message], '错误');
            end
        end

        % Button pushed function: predictbutton
        function predictbuttonButtonPushed(app, event)

            % 获取原始灰度图像
            img = getControlImage(app, {'originImage_10','originImage_2','original_image'});
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); 
                return; 
            end
            
            % 确保是灰度图像
            if size(img,3) == 3
                img = rgb2gray(img);
            end
            
            % 显示原始图像
            setControlImage(app, {'originImage_10'}, img);
            
            % 预测编码 (使用 'left' 模式)
            try
                % 如果图像太大,先缩小以加快处理
                [h, w] = size(img);
                original_size = [h, w];
                if h * w > 512 * 512
                    scale = sqrt((512*512) / (h*w));
                    img = imresize(img, scale);
                    fprintf('图像已缩放至 %dx%d 以加快处理\n', size(img,1), size(img,2));
                end
                
                E = pred_encode(img, 'left');
                G_pred_rec = pred_decode(E);
                
                % 验证编解码是否无损
                ok_pred = isequal(img, G_pred_rec);
                
                % 对预测误差做 Huffman 编码
                res_shift = uint16(E.residual + 32768);  % 偏移到非负
                H_res = mat2huff(res_shift);
                
                % 计算压缩比
                bits_raw = numel(img) * 8;
                bits_predH = numel(H_res.bitstream);
                ratio_predH = bits_raw / bits_predH;
                
                % 显示预测残差分布直方图到UIAxes
                % 在UIAxes上显示残差直方图
                axes(app.predictImage);
                cla(app.predictImage);
                histogram(E.residual(:), 'FaceColor', [0.2 0.5 0.8], 'EdgeColor', 'none');
                xlabel('预测误差值', 'Color', 'w', 'FontSize', 10);
                ylabel('频数', 'Color', 'w', 'FontSize', 10);
                title('预测误差分布 (pred\_encode residual)', 'Color', 'w', 'FontSize', 12, 'Interpreter', 'tex');
                grid on;
                set(gca, 'Color', [0.1 0.1 0.1], 'XColor', 'w', 'YColor', 'w', 'GridColor', [0.3 0.3 0.3]);
                
                % 显示统计信息
                if ok_pred
                    status_text = '正确';
                else
                    status_text = '错误';
                end
                
                msg = sprintf(['预测编码结果:\n' ...
                    '原始: %d bits\n' ...
                    '预测+Huffman: %d bits\n' ...
                    '压缩比: %.3f\n' ...
                    '节省空间: %.2f%%\n' ...
                    '解码正确性: %s'], ...
                    bits_raw, bits_predH, ratio_predH, ...
                    (1 - bits_predH/bits_raw) * 100, ...
                    status_text);
                
                uialert(app.UIFigure, msg, '预测编码', 'Icon', 'info');
                
            catch ME
                uialert(app.UIFigure, ['预测编码失败: ' ME.message], '错误');
            end
        end

        % Button pushed function: Convert
        function ConvertButtonPushed(app, event)
  % RGB/HSI互相转换功能 - 单一输出到originImage_11
            
            % 获取原始图像
            img = getControlImage(app, {'originImage_6','original_image'});
            if isempty(img)
                uialert(app.UIFigure,'请先加载图片','错误'); 
                return; 
            end
            
            % 判断当前显示模式：通过检查转换状态标记
            % 使用 Convert 按钮的 UserData 来存储当前模式状态
            % 'RGB' = 当前显示RGB，需要转换到HSI (生成HSI三通道合成图)
            % 'HSI' = 当前显示HSI，需要转换回RGB
            
            try
                % 获取当前模式状态（默认为 'RGB'）
                current_mode = 'RGB';
                try
                    if isprop(app, 'Convert') && ~isempty(app.Convert.UserData)
                        current_mode = app.Convert.UserData;
                    end
                catch
                end
                
                % 根据当前模式决定转换方向
                is_hsi_mode = strcmp(current_mode, 'HSI');
                
                if is_hsi_mode
                    % 当前是HSI模式 -> 转换回RGB
                    % 从 originImage_11.UserData 中恢复存储的HSI数据
                    
                    try
                        % 从originImage_11.UserData中恢复HSI数据（之前存储的）
                        if isprop(app, 'originImage_11') && isstruct(app.originImage_11.UserData)
                            hsi_data = app.originImage_11.UserData;
                            H = hsi_data.H;
                            S = hsi_data.S;
                            I = hsi_data.I;
                        else
                            uialert(app.UIFigure,'未找到HSI数据,请先执行RGB→HSI转换','错误');
                            return;
                        end
                    catch
                        uialert(app.UIFigure,'HSI数据读取失败','错误');
                        return;
                    end
                    
                    % HSI -> RGB转换
                    rgb_result = app.hsi2rgb_app(H, S, I);
                    rgb_result_uint8 = im2uint8(rgb_result);
                    
                    % 只显示到 originImage_11
                    try
                        app.originImage_11.ImageSource = rgb_result_uint8;
                        app.originImage_11.UserData = [];  % 清空HSI数据
                    catch
                        setControlImage(app, {'originImage_11'}, rgb_result_uint8);
                        app.originImage_11.UserData = [];
                    end
                    
                    uialert(app.UIFigure,'HSI → RGB 转换完成','成功','Icon','success');
                    
                    % 切换模式状态为 'RGB'
                    app.Convert.UserData = 'RGB';
                    
                else
                    % 当前是RGB模式 -> 转换到HSI
                    if size(img,3) ~= 3
                        uialert(app.UIFigure,'请加载RGB彩色图像(3通道)','错误'); 
                        return;
                    end
                    
                    % RGB -> HSI转换 (参考rgb2hsi.m的标准算法)
                    rgb_double = im2double(img);
                    R = rgb_double(:,:,1);
                    G = rgb_double(:,:,2);
                    B = rgb_double(:,:,3);
                    
                    % 计算色调H
                    num = 0.5 * ((R - G) + (R - B));
                    den = sqrt((R - G).^2 + (R - B).*(G - B)) + eps;
                    theta = acos(num ./ den);
                    
                    H = theta;
                    H(B > G) = 2*pi - H(B > G);  % 注意:判断条件是B>G
                    H = H / (2*pi);  % 归一化到[0,1]
                    
                    % 计算饱和度S
                    minRGB = min(min(R, G), B);
                    sumRGB = R + G + B;
                    S = 1 - 3 .* minRGB ./ (sumRGB + eps);
                    S(sumRGB == 0) = 0;
                    
                    % 计算强度I
                    I = sumRGB / 3;
                    
                    % 创建HSI合成图像用于显示 (参考RGB.m的显示方式)
                    % 将H/S/I作为三通道合成为一张彩色图像
                    hsi_composite = cat(3, H, S, I);
                    
                    % 存储HSI原始数据到originImage_11.UserData供后续转换使用
                    hsi_data.H = H;
                    hsi_data.S = S;
                    hsi_data.I = I;
                    
                    % 只显示到 originImage_11，并将HSI数据存储在UserData中
                    try
                        app.originImage_11.ImageSource = hsi_composite;
                        app.originImage_11.UserData = hsi_data;  % 存储结构体
                    catch
                        setControlImage(app, {'originImage_11'}, hsi_composite);
                        app.originImage_11.UserData = hsi_data;
                    end
                    
                    uialert(app.UIFigure,'RGB → HSI 转换完成','成功','Icon','success');
                    
                    % 切换模式状态为 'HSI'
                    app.Convert.UserData = 'HSI';
                end
                
            catch ME
                uialert(app.UIFigure, ['转换失败: ' ME.message], '错误');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.background = uipanel(app.UIFigure);
            app.compression_background = uipanel(app.background);
            app.Predictive = uipanel(app.compression_background);
            app.predictImage = uiaxes(app.Predictive);
            xlabel(app.predictImage, 'X')
            ylabel(app.predictImage, 'Y')
            zlabel(app.predictImage, 'Z')
            app.originImage_10 = uiimage(app.Predictive);
            app.Label_19 = uilabel(app.Predictive);
            app.Label_19.Text = '原始图像';
            app.Label_45 = uilabel(app.Predictive);
            app.Label_45.Text = '预测误差分布';
            app.predictbutton = uibutton(app.Predictive, 'push');
            app.predictbutton.ButtonPushedFcn = createCallbackFcn(app, @predictbuttonButtonPushed, true);
            app.predictbutton.Text = '预测';
            app.Huffman = uipanel(app.compression_background);
            app.originImage_9 = uiimage(app.Huffman);
            app.Label_18 = uilabel(app.Huffman);
            app.Label_18.Text = '原始图像';
            app.Huffmanbutton = uibutton(app.Huffman, 'push');
            app.Huffmanbutton.ButtonPushedFcn = createCallbackFcn(app, @HuffmanbuttonButtonPushed, true);
            app.Huffmanbutton.Text = '霍夫曼';
            app.HTML = uihtml(app.Huffman);
            app.Label_44 = uilabel(app.Huffman);
            app.Label_44.Text = '输出';
            app.topbar_Predictive = uibutton(app.compression_background, 'state');
            app.topbar_Predictive.ValueChangedFcn = createCallbackFcn(app, @topbar_PredictiveValueChanged, true);
            app.topbar_Predictive.Text = '预测编码';
            app.topbar_Huffman = uibutton(app.compression_background, 'state');
            app.topbar_Huffman.ValueChangedFcn = createCallbackFcn(app, @topbar_HuffmanValueChanged, true);
            app.topbar_Huffman.Text = '霍夫曼编码';
            app.color_background = uipanel(app.background);
            app.Full_color = uipanel(app.color_background);
            app.originImage_8 = uiimage(app.Full_color);
            app.Label_17 = uilabel(app.Full_color);
            app.Label_17.Text = '原始图像';
            app.Full_colorImage = uiimage(app.Full_color);
            app.Label_33 = uilabel(app.Full_color);
            app.Label_33.Text = '全彩色图像';
            app.RGBbutton = uibutton(app.Full_color, 'push');
            app.RGBbutton.ButtonPushedFcn = createCallbackFcn(app, @RGBbuttonButtonPushed, true);
            app.RGBbutton.Text = 'RGB';
            app.HSIbutton = uibutton(app.Full_color, 'push');
            app.HSIbutton.ButtonPushedFcn = createCallbackFcn(app, @HSIbuttonButtonPushed, true);
            app.HSIbutton.Text = 'HSI';
            app.Pseudocolor = uipanel(app.color_background);
            app.originImage_7 = uiimage(app.Pseudocolor);
            app.Label_16 = uilabel(app.Pseudocolor);
            app.Label_16.Text = '原始图像';
            app.PseudocolorImage = uiimage(app.Pseudocolor);
            app.Label_32 = uilabel(app.Pseudocolor);
            app.Label_32.Text = '伪彩色图像';
            app.Pseudocolorbutton = uibutton(app.Pseudocolor, 'push');
            app.Pseudocolorbutton.ButtonPushedFcn = createCallbackFcn(app, @PseudocolorbuttonButtonPushed, true);
            app.Pseudocolorbutton.Text = '伪彩色图像处理';
            app.color = uipanel(app.color_background);
            app.originImage_6 = uiimage(app.color);
            app.Label_15 = uilabel(app.color);
            app.Label_15.Text = '原始图像';
            app.HImage = uiimage(app.color);
            app.SImage = uiimage(app.color);
            app.IImage = uiimage(app.color);
            app.enhanceImage = uiimage(app.color);
            app.colorImage = uiimage(app.color);
            app.HLabel = uilabel(app.color);
            app.HLabel.Text = 'H/红色通道';
            app.SLabel = uilabel(app.color);
            app.SLabel.Text = 'S/绿色通道';
            app.ILabel = uilabel(app.color);
            app.ILabel.Text = 'I/蓝色通道';
            app.Label_41 = uilabel(app.color);
            app.Label_41.Text = '饱和度增强/红色灰度图';
            app.Label_42 = uilabel(app.color);
            app.Label_42.Text = '色调旋转/绿色灰度图';
            app.RGB = uibutton(app.color, 'push');
            app.RGB.ButtonPushedFcn = createCallbackFcn(app, @RGBButtonPushed, true);
            app.RGB.Text = 'RGB';
            app.HSI = uibutton(app.color, 'push');
            app.HSI.ButtonPushedFcn = createCallbackFcn(app, @HSIButtonPushed, true);
            app.HSI.Text = 'HSI';
            app.lightImage = uiimage(app.color);
            app.Label_43 = uilabel(app.color);
            app.Label_43.Text = '亮度降低/蓝色灰度图';
            app.originImage_11 = uiimage(app.color);
            app.RGBHSILabel = uilabel(app.color);
            app.RGBHSILabel.Text = 'RGB/HSI互相转换';
            app.Convert = uibutton(app.color, 'push');
            app.Convert.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.Convert.Text = 'RGB/HSI互相转换';
            app.topbar_Full_color = uibutton(app.color_background, 'state');
            app.topbar_Full_color.ValueChangedFcn = createCallbackFcn(app, @topbar_Full_colorValueChanged, true);
            app.topbar_Full_color.Text = '全彩色图像处理';
            app.topbar_Pseudocolor = uibutton(app.color_background, 'state');
            app.topbar_Pseudocolor.ValueChangedFcn = createCallbackFcn(app, @topbar_PseudocolorValueChanged, true);
            app.topbar_Pseudocolor.Text = '伪彩色图像处理';
            app.topbar_color = uibutton(app.color_background, 'state');
            app.topbar_color.ValueChangedFcn = createCallbackFcn(app, @topbar_colorValueChanged, true);
            app.topbar_color.Text = '彩色模型';
            app.restoration_background = uipanel(app.background);
            app.reconstructed = uipanel(app.restoration_background);
            app.PSFImage_3 = uiaxes(app.reconstructed);
            xlabel(app.PSFImage_3, 'X')
            ylabel(app.PSFImage_3, 'Y')
            zlabel(app.PSFImage_3, 'Z')
            app.PSFImage_4 = uiaxes(app.reconstructed);
            xlabel(app.PSFImage_4, 'X')
            ylabel(app.PSFImage_4, 'Y')
            zlabel(app.PSFImage_4, 'Z')
            app.originImage_5 = uiimage(app.reconstructed);
            app.Label_14 = uilabel(app.reconstructed);
            app.Label_14.Text = '原始图像';
            app.Label_31 = uilabel(app.reconstructed);
            app.Label_31.Text = '输出图像';
            app.RAdon = uipanel(app.reconstructed);
            app.Radon = uibutton(app.RAdon, 'push');
            app.Radon.ButtonPushedFcn = createCallbackFcn(app, @RadonButtonPushed, true);
            app.Radon.Text = 'Radon变换';
            app.Label_36 = uilabel(app.RAdon);
            app.Label_36.Text = '投影度数';
            app.Label_40 = uilabel(app.RAdon);
            app.Label_40.Text = '';
            app.degree = uislider(app.RAdon);
            app.degree.Limits = [0 90];
            app.Label_35 = uilabel(app.reconstructed);
            app.Label_35.Text = '输出图像';
            app.Fourier_slice = uipanel(app.reconstructed);
            app.Label_37 = uilabel(app.Fourier_slice);
            app.Label_37.Text = '投影度数';
            app.Fourier = uibutton(app.Fourier_slice, 'push');
            app.Fourier.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.Fourier.Text = '傅里叶切片定理';
            app.Label_39 = uilabel(app.Fourier_slice);
            app.Label_39.Text = '';
            app.degree_1 = uislider(app.Fourier_slice);
            app.degree_1.Limits = [0 90];
            app.Back_projection = uipanel(app.reconstructed);
            app.projection = uibutton(app.Back_projection, 'push');
            app.projection.ButtonPushedFcn = createCallbackFcn(app, @projectionButtonPushed, true);
            app.projection.Text = '反投影计算';
            app.model_2 = uidropdown(app.Back_projection);
            app.model_2.Items = {'ram-lak滤波器', 'shepp-logan滤波器', 'cosine滤波器', 'hamming滤波器', 'hann滤波器'};
            app.model_2.Value = 'ram-lak滤波器';
            app.Label_38 = uilabel(app.Back_projection);
            app.Label_38.Text = '滤波器选择';
            app.Inverse_wiener = uipanel(app.restoration_background);
            app.originImage_4 = uiimage(app.Inverse_wiener);
            app.Label_13 = uilabel(app.Inverse_wiener);
            app.Label_13.Text = '原始图像';
            app.plural_images_2 = uiimage(app.Inverse_wiener);
            app.Label_28 = uilabel(app.Inverse_wiener);
            app.Label_28.Text = '输出图像';
            app.Wiener = uipanel(app.Inverse_wiener);
            app.Wienerbutton = uibutton(app.Wiener, 'push');
            app.Wienerbutton.ButtonPushedFcn = createCallbackFcn(app, @WienerbuttonButtonPushed, true);
            app.Wienerbutton.Text = '维纳滤波';
            app.Inverse = uipanel(app.Inverse_wiener);
            app.Inversebutton = uibutton(app.Inverse, 'push');
            app.Inversebutton.ButtonPushedFcn = createCallbackFcn(app, @InversebuttonButtonPushed, true);
            app.Inversebutton.Text = '逆滤波';
            app.Label_29 = uilabel(app.Inverse);
            app.Label_29.Text = '滤波模版';
            app.stencil_9 = uidropdown(app.Inverse);
            app.stencil_9.Items = {'传统逆滤波', '阈值法改进逆滤波', '频率限制法逆滤波', '正则化法逆滤波'};
            app.stencil_9.Value = '传统逆滤波';
            app.noise_spatial = uipanel(app.restoration_background);
            app.originImage_3 = uiimage(app.noise_spatial);
            app.Label_12 = uilabel(app.noise_spatial);
            app.Label_12.Text = '原始图像';
            app.mixed = uipanel(app.noise_spatial);
            app.mixedbutton = uibutton(app.mixed, 'push');
            app.mixedbutton.ButtonPushedFcn = createCallbackFcn(app, @mixedbuttonButtonPushed, true);
            app.mixedbutton.Text = '混合噪声';
            app.Label_22 = uilabel(app.mixed);
            app.Label_22.Text = '滤波模版';
            app.stencil_4 = uidropdown(app.mixed);
            app.stencil_4.Items = {'中值滤波', '阿尔法均值滤波'};
            app.stencil_4.Value = '中值滤波';
            app.Sequential_Gaussian = uipanel(app.noise_spatial);
            app.SequentialGaussian = uibutton(app.Sequential_Gaussian, 'push');
            app.SequentialGaussian.ButtonPushedFcn = createCallbackFcn(app, @SequentialGaussianButtonPushed, true);
            app.SequentialGaussian.Text = '顺序统计滤波器(高斯噪声)';
            app.Label_23 = uilabel(app.Sequential_Gaussian);
            app.Label_23.Text = '滤波模版';
            app.stencil_5 = uidropdown(app.Sequential_Gaussian);
            app.stencil_5.Items = {'中值滤波', '最大值滤波', '最小值滤波', '中点滤波', '阿尔法均值滤波'};
            app.stencil_5.Value = '中值滤波';
            app.Mean_Gaussian = uipanel(app.noise_spatial);
            app.MeanGaussian = uibutton(app.Mean_Gaussian, 'push');
            app.MeanGaussian.ButtonPushedFcn = createCallbackFcn(app, @MeanGaussianButtonPushed, true);
            app.MeanGaussian.Text = '均值滤波器(高斯噪声)';
            app.Label_24 = uilabel(app.Mean_Gaussian);
            app.Label_24.Text = '滤波模版';
            app.stencil_6 = uidropdown(app.Mean_Gaussian);
            app.stencil_6.Items = {'算术均值滤波', '逆谐波均值滤波（Q=1.5）', '逆谐波均值滤波(Q=-1.5)', '逆谐波滤波', '几何均值滤波'};
            app.stencil_6.Value = '算术均值滤波';
            app.Sequential_salt = uipanel(app.noise_spatial);
            app.Sequentialsalt = uibutton(app.Sequential_salt, 'push');
            app.Sequentialsalt.ButtonPushedFcn = createCallbackFcn(app, @SequentialsaltButtonPushed, true);
            app.Sequentialsalt.Text = '顺序统计滤波器(椒盐噪声)';
            app.Label_25 = uilabel(app.Sequential_salt);
            app.Label_25.Text = '滤波模版';
            app.stencil_7 = uidropdown(app.Sequential_salt);
            app.stencil_7.Items = {'中值滤波', '最大值滤波', '最小值滤波', '中点滤波', '阿尔法均值滤波'};
            app.stencil_7.Value = '中值滤波';
            app.Mean_salt = uipanel(app.noise_spatial);
            app.Meansalt = uibutton(app.Mean_salt, 'push');
            app.Meansalt.ButtonPushedFcn = createCallbackFcn(app, @MeansaltButtonPushed, true);
            app.Meansalt.Text = '均值滤波器（椒盐噪声）';
            app.Label_26 = uilabel(app.Mean_salt);
            app.Label_26.Text = '滤波模版';
            app.stencil_8 = uidropdown(app.Mean_salt);
            app.stencil_8.Items = {'算术均值滤波', '逆谐波均值滤波（Q=1.5）', '逆谐波均值滤波(Q=-1.5)', '逆谐波滤波', '几何均值滤波'};
            app.stencil_8.Value = '算术均值滤波';
            app.plural_images = uiimage(app.noise_spatial);
            app.Label_27 = uilabel(app.noise_spatial);
            app.Label_27.Text = '复原图像';
            app.noise = uipanel(app.restoration_background);
            app.PSFImage_2 = uiaxes(app.noise);
            xlabel(app.PSFImage_2, 'X')
            ylabel(app.PSFImage_2, 'Y')
            zlabel(app.PSFImage_2, 'Z')
            app.salt = uibutton(app.noise, 'push');
            app.salt.ButtonPushedFcn = createCallbackFcn(app, @saltButtonPushed, true);
            app.salt.Text = '椒盐噪声';
            app.uniformly = uibutton(app.noise, 'push');
            app.uniformly.ButtonPushedFcn = createCallbackFcn(app, @uniformlyButtonPushed, true);
            app.uniformly.Text = '均匀分布噪声';
            app.Gaussian_2 = uibutton(app.noise, 'push');
            app.Gaussian_2.ButtonPushedFcn = createCallbackFcn(app, @Gaussian_2ButtonPushed, true);
            app.Gaussian_2.Text = '高斯噪声';
            app.degradedImage_2 = uiimage(app.noise);
            app.originImage_2 = uiimage(app.noise);
            app.Label_9 = uilabel(app.noise);
            app.Label_9.Text = '原始图像';
            app.Label_10 = uilabel(app.noise);
            app.Label_10.Text = '噪声图像';
            app.Label_11 = uilabel(app.noise);
            app.Label_11.Text = '噪声直方图';
            app.degradation = uipanel(app.restoration_background);
            app.PSFImage = uiaxes(app.degradation);
            xlabel(app.PSFImage, 'X')
            ylabel(app.PSFImage, 'Y')
            zlabel(app.PSFImage, 'Z')
            app.Defocusing = uibutton(app.degradation, 'push');
            app.Defocusing.ButtonPushedFcn = createCallbackFcn(app, @DefocusingButtonPushed, true);
            app.Defocusing.Text = '散焦模糊退化';
            app.Gaussian = uibutton(app.degradation, 'push');
            app.Gaussian.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.Gaussian.Text = '高斯模糊退化';
            app.Motion = uibutton(app.degradation, 'push');
            app.Motion.ButtonPushedFcn = createCallbackFcn(app, @MotionButtonPushed, true);
            app.Motion.Text = '运动模糊退化';
            app.degradedImage = uiimage(app.degradation);
            app.originImage = uiimage(app.degradation);
            app.Label_7 = uilabel(app.degradation);
            app.Label_7.Text = '原始图像';
            app.Label_8 = uilabel(app.degradation);
            app.Label_8.Text = '退化图像';
            app.PSFLabel = uilabel(app.degradation);
            app.PSFLabel.Text = 'PSF图像';
            app.topbar_reconstructed = uibutton(app.restoration_background, 'state');
            app.topbar_reconstructed.ValueChangedFcn = createCallbackFcn(app, @topbar_reconstructedValueChanged, true);
            app.topbar_reconstructed.Text = '由投影重建图像';
            app.topbar_Inverse_wiener = uibutton(app.restoration_background, 'state');
            app.topbar_Inverse_wiener.ValueChangedFcn = createCallbackFcn(app, @topbar_Inverse_wienerValueChanged, true);
            app.topbar_Inverse_wiener.Text = '逆滤波和维纳滤波';
            app.topbar_noise_spatial = uibutton(app.restoration_background, 'state');
            app.topbar_noise_spatial.ValueChangedFcn = createCallbackFcn(app, @topbar_noise_spatialValueChanged, true);
            app.topbar_noise_spatial.Text = '仅有噪声的复原-空间滤波';
            app.topbar_noise = uibutton(app.restoration_background, 'state');
            app.topbar_noise.ValueChangedFcn = createCallbackFcn(app, @topbar_noiseValueChanged, true);
            app.topbar_noise.Text = '噪声模型';
            app.topbar_degradation = uibutton(app.restoration_background, 'state');
            app.topbar_degradation.ValueChangedFcn = createCallbackFcn(app, @topbar_degradationValueChanged, true);
            app.topbar_degradation.Text = '图像退化模型';
            app.enhance_background = uipanel(app.background);
            app.frequency = uipanel(app.enhance_background);
            app.DFT = uipanel(app.frequency);
            app.DFTbutton = uibutton(app.DFT, 'push');
            app.DFTbutton.ButtonPushedFcn = createCallbackFcn(app, @DFTbuttonButtonPushed, true);
            app.DFTbutton.Text = 'DFT';
            app.gammaLabel_3 = uilabel(app.DFT);
            app.gammaLabel_3.Text = '行数';
            app.row = uieditfield(app.DFT, 'numeric');
            app.gammaLabel_5 = uilabel(app.DFT);
            app.gammaLabel_5.Text = '列数';
            app.column = uieditfield(app.DFT, 'numeric');
            app.Label_4 = uilabel(app.DFT);
            app.Label_4.Text = '位移';
            app.Displacement = uidropdown(app.DFT);
            app.Displacement.Items = {'true', 'false'};
            app.Displacement.Value = 'true';
            app.DropDown_3Label_3 = uilabel(app.DFT);
            app.DropDown_3Label_3.Text = 'log';
            app.log = uidropdown(app.DFT);
            app.log.Items = {'true', 'false'};
            app.log.Value = 'true';
            app.low = uipanel(app.frequency);
            app.lowbutton = uibutton(app.low, 'push');
            app.lowbutton.ButtonPushedFcn = createCallbackFcn(app, @lowbuttonButtonPushed, true);
            app.lowbutton.Text = '低通滤波';
            app.Label_5 = uilabel(app.low);
            app.Label_5.Text = '滤波模式';
            app.lowmode = uidropdown(app.low);
            app.lowmode.Items = {'理想低通', '高斯', '巴特沃斯'};
            app.lowmode.Value = '理想低通';
            app.high = uipanel(app.frequency);
            app.highbutton = uibutton(app.high, 'push');
            app.highbutton.ButtonPushedFcn = createCallbackFcn(app, @highbuttonButtonPushed, true);
            app.highbutton.Text = '高通滤波';
            app.Label_6 = uilabel(app.high);
            app.Label_6.Text = '滤波模式';
            app.highmode = uidropdown(app.high);
            app.highmode.Items = {'理想高通', '高斯', '巴特沃斯'};
            app.highmode.Value = '理想高通';
            app.original_image_2 = uiimage(app.frequency);
            app.original_2 = uilabel(app.frequency);
            app.original_2.Text = '原始图像';
            app.enhance_2 = uilabel(app.frequency);
            app.enhance_2.Text = '增强图像';
            app.enhance_image_2 = uiimage(app.frequency);
            app.spatial = uipanel(app.enhance_background);
            app.Gray = uipanel(app.spatial);
            app.Grayscale = uibutton(app.Gray, 'push');
            app.Grayscale.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.Grayscale.Text = '灰度变换';
            app.gammaLabel = uilabel(app.Gray);
            app.gammaLabel.Text = 'gamma';
            app.graygamma = uieditfield(app.Gray, 'numeric');
            app.grayinLabel = uilabel(app.Gray);
            app.grayinLabel.Text = 'grayin';
            app.grayin = uislider(app.Gray, 'range');
            app.grayin.Limits = [0 1];
            app.grayin.Value = [0 1];
            app.grayoutSliderLabel = uilabel(app.Gray);
            app.grayoutSliderLabel.Text = 'grayout';
            app.grayoutSlider = uislider(app.Gray, 'range');
            app.grayoutSlider.Limits = [0 1];
            app.grayoutSlider.Value = [0 1];
            app.Histogram = uipanel(app.spatial);
            app.gammaLabel_2 = uilabel(app.Histogram);
            app.gammaLabel_2.Text = '灰度级数';
            app.grayLevel = uieditfield(app.Histogram, 'numeric');
            app.HistogramEqualization = uibutton(app.Histogram, 'push');
            app.HistogramEqualization.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualization.Text = '直方图均衡化';
            app.spatial_filtering = uipanel(app.spatial);
            app.spatialFiltering = uibutton(app.spatial_filtering, 'push');
            app.spatialFiltering.ButtonPushedFcn = createCallbackFcn(app, @spatialFilteringButtonPushed, true);
            app.spatialFiltering.Text = '空间滤波';
            app.Label_2 = uilabel(app.spatial_filtering);
            app.Label_2.Text = '滤波模版';
            app.stencil = uidropdown(app.spatial_filtering);
            app.stencil.Items = {'全1矩阵', '全0矩阵', '高斯'};
            app.stencil.Value = '全1矩阵';
            app.DropDown_2Label = uilabel(app.spatial_filtering);
            app.DropDown_2Label.Text = '滤波模式';
            app.model = uidropdown(app.spatial_filtering);
            app.model.Items = {'corr', 'conv'};
            app.model.Value = 'corr';
            app.DropDown_3Label = uilabel(app.spatial_filtering);
            app.DropDown_3Label.Text = '边界处理';
            app.Boundary = uidropdown(app.spatial_filtering);
            app.Boundary.Items = {'0', 'replicate', 'symmetric', 'circular'};
            app.Boundary.Value = '0';
            app.original = uilabel(app.spatial);
            app.original.Text = '原始图像';
            app.original_image = uiimage(app.spatial);
            app.enhance = uilabel(app.spatial);
            app.enhance.Text = '增强图像';
            app.enhance_image = uiimage(app.spatial);
            app.topbar_frequency = uibutton(app.enhance_background, 'state');
            app.topbar_frequency.ValueChangedFcn = createCallbackFcn(app, @topbar_frequencyValueChanged, true);
            app.topbar_frequency.Text = '频率域方法';
            app.topbar_spatial = uibutton(app.enhance_background, 'state');
            app.topbar_spatial.ValueChangedFcn = createCallbackFcn(app, @topbar_spatialValueChanged, true);
            app.topbar_spatial.Text = '空间域方法';
            app.topbar_spatial.Value = true;
            app.save = uibutton(app.background, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = '保存图像';
            app.load = uibutton(app.background, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.load.Text = '加载图像';
            app.SideBar_compression = uibutton(app.background, 'state');
            app.SideBar_compression.ValueChangedFcn = createCallbackFcn(app, @SideBar_compressionValueChanged, true);
            app.SideBar_compression.Icon = fullfile(pathToMLAPP, 'imageicon', '4.png');
            app.SideBar_compression.Text = '图像压缩';
            app.SideBar_color = uibutton(app.background, 'state');
            app.SideBar_color.ValueChangedFcn = createCallbackFcn(app, @SideBar_colorValueChanged, true);
            app.SideBar_color.Icon = fullfile(pathToMLAPP, 'imageicon', '3.png');
            app.SideBar_color.Text = '彩色图像处理';
            app.SideBar_restoration = uibutton(app.background, 'state');
            app.SideBar_restoration.ValueChangedFcn = createCallbackFcn(app, @SideBar_restorationValueChanged, true);
            app.SideBar_restoration.Icon = fullfile(pathToMLAPP, 'imageicon', '下载 (1).png');
            app.SideBar_restoration.Text = '图像复原与重建';
            app.SideBar_enhancement = uibutton(app.background, 'state');
            app.SideBar_enhancement.ValueChangedFcn = createCallbackFcn(app, @SideBar_enhancementValueChanged, true);
            app.SideBar_enhancement.Icon = fullfile(pathToMLAPP, 'imageicon', '1.png');
            app.SideBar_enhancement.Text = '图像增强';
            app.SideBar_enhancement.Value = true;
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'html';
            app.Label.Text = '数字图像处理';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end